classdef ClusToRa_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ClusToRaAnalysisTab             matlab.ui.container.Tab
        xygCheckBox                     matlab.ui.control.CheckBox
        metaCheckBox                    matlab.ui.control.CheckBox
        advancedDropDown                matlab.ui.control.DropDown
        advancedDropDownLabel           matlab.ui.control.Label
        optionalLabel_5                 matlab.ui.control.Label
        barcodescolumn                  matlab.ui.control.TextArea
        assignbarcodesButton            matlab.ui.control.Button
        delimiterDropDown               matlab.ui.control.DropDown
        delimiterDropDownLabel          matlab.ui.control.Label
        CelltypeLevel2Options           matlab.ui.control.DropDown
        Lamp                            matlab.ui.control.Lamp
        autosaveCheckBox                matlab.ui.control.CheckBox
        LoadButton                      matlab.ui.control.Button
        progressGauge                   matlab.ui.control.LinearGauge
        progressGaugeLabel              matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ParametersPanel                 matlab.ui.container.Panel
        clumpinessCheckBox              matlab.ui.control.CheckBox
        epsilonUser                     matlab.ui.control.NumericEditField
        ThreadsSpinner                  matlab.ui.control.Spinner
        ThreadsSpinnerLabel             matlab.ui.control.Label
        selfCheckBox                    matlab.ui.control.CheckBox
        epsilonDropDown                 matlab.ui.control.DropDown
        epsilonDropDownLabel            matlab.ui.control.Label
        randomSpinner                   matlab.ui.control.Spinner
        randomSpinnerLabel              matlab.ui.control.Label
        mincellsSpinner                 matlab.ui.control.Spinner
        mincellsSpinnerLabel            matlab.ui.control.Label
        StartClusToRaButton             matlab.ui.control.Button
        requiredLabel_3                 matlab.ui.control.Label
        optionalLabel_4                 matlab.ui.control.Label
        requiredLabel_2                 matlab.ui.control.Label
        optionalLabel_2                 matlab.ui.control.Label
        requiredLabel                   matlab.ui.control.Label
        optionalLabel                   matlab.ui.control.Label
        SamplesLevel2input              matlab.ui.control.TextArea
        SamplesLevel1input              matlab.ui.control.TextArea
        SamplesLevel2CheckBox           matlab.ui.control.CheckBox
        CelltypeLevel2input             matlab.ui.control.TextArea
        CelltypeLevel1input             matlab.ui.control.TextArea
        Ycoordinput                     matlab.ui.control.TextArea
        Xcoordinput                     matlab.ui.control.TextArea
        ColumnsListBox                  matlab.ui.control.ListBox
        ColumnsListBoxLabel             matlab.ui.control.Label
        SamplesLevel1CheckBox           matlab.ui.control.CheckBox
        CelltypeLevel2CheckBox          matlab.ui.control.CheckBox
        CelltypeLevel1CheckBox          matlab.ui.control.CheckBox
        YcoordCheckBox                  matlab.ui.control.CheckBox
        XcoordCheckBox                  matlab.ui.control.CheckBox
        AssignButton                    matlab.ui.control.Button
        PreviewButton                   matlab.ui.control.Button
        SelectedFilePath                matlab.ui.control.TextArea
        LoadmetadataButton              matlab.ui.control.Button
        ClusToRaPlottingTab             matlab.ui.container.Tab
        ColocMatrixFileType             matlab.ui.control.DropDown
        ColocMatrixButton               matlab.ui.control.Button
        ClusterSummary                  matlab.ui.container.Panel
        CircleSize                      matlab.ui.control.DropDown
        filloptionLabel_2               matlab.ui.control.Label
        CircleFill                      matlab.ui.control.DropDown
        filloptionLabel                 matlab.ui.control.Label
        ClustersbarssummaryButton       matlab.ui.control.Button
        SavePlotsButton                 matlab.ui.control.Button
        DPILabel                        matlab.ui.control.Label
        DPItosave                       matlab.ui.control.DropDown
        filetypeLabel                   matlab.ui.control.Label
        filetypetosave                  matlab.ui.control.DropDown
        SaveFigures                     matlab.ui.container.Panel
        CellTypePreviewButton           matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        SelectCellsToScatterPlotNode    matlab.ui.container.TreeNode
        ScatterparametersPanel          matlab.ui.container.Panel
        alphaSpinner_2                  matlab.ui.control.Spinner
        alphaSpinner_2Label             matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_1                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        PlotScatterButton               matlab.ui.control.Button
        MarkerSizeSpinner               matlab.ui.control.Spinner
        Label                           matlab.ui.control.Label
        ColocalizingCheckBox            matlab.ui.control.CheckBox
        ClustersCheckBox                matlab.ui.control.CheckBox
        ClusternonClusterCheckBox       matlab.ui.control.CheckBox
        AllselectedCheckBox             matlab.ui.control.CheckBox
        ScatterOptions                  matlab.ui.control.DropDown
        ChordplotparametersPanel        matlab.ui.container.Panel
        cMaxChord                       matlab.ui.control.DropDown
        minsampleSpinner                matlab.ui.control.Spinner
        minsampleLabel                  matlab.ui.control.Label
        PlotChordButton                 matlab.ui.control.Button
        colorsDropDown                  matlab.ui.control.DropDown
        colorsDropDownLabel             matlab.ui.control.Label
        alphaSpinner                    matlab.ui.control.Spinner
        alphaSpinnerLabel               matlab.ui.control.Label
        ToplotpreviewDropDown           matlab.ui.control.DropDown
        ToplotpreviewLabel              matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapLabel                   matlab.ui.control.Label
        ThreshSpinner                   matlab.ui.control.Spinner
        ThreshSpinnerLabel              matlab.ui.control.Label
        scoreDropDown                   matlab.ui.control.DropDown
        scoreLabel                      matlab.ui.control.Label
        ColorMap                        matlab.ui.control.UIAxes
        Level1ListBox                   matlab.ui.control.ListBox
        Level1ListBoxLabel              matlab.ui.control.Label
        SwitchLoad                      matlab.ui.control.Switch
        importButton                    matlab.ui.control.Button
        Level2ListBox                   matlab.ui.control.ListBox
        Level2ListBoxLabel              matlab.ui.control.Label
        ClusToRaDifferentialTab         matlab.ui.container.Tab
        DiffSetupPanel                  matlab.ui.container.Panel
        DiffrunprogressGauge            matlab.ui.control.LinearGauge
        DiffrunprogressGaugeLabel       matlab.ui.control.Label
        pairsTextArea                   matlab.ui.control.TextArea
        pairsTextAreaLabel              matlab.ui.control.Label
        Diff_selected                   matlab.ui.control.TextArea
        Hyperlink                       matlab.ui.control.Hyperlink
        AdddatabaseButton               matlab.ui.control.Button
        GSEApanel                       matlab.ui.container.Panel
        minGenesSpinner                 matlab.ui.control.Spinner
        minGenesSpinnerLabel            matlab.ui.control.Label
        EnrichralphaSpinner             matlab.ui.control.Spinner
        EnrichralphaSpinnerLabel        matlab.ui.control.Label
        DiffType                        matlab.ui.control.DropDown
        typeDropDown_2Label             matlab.ui.control.Label
        DiffalphaSpinner                matlab.ui.control.Spinner
        DiffalphaSpinnerLabel           matlab.ui.control.Label
        GeneAnalysisButton              matlab.ui.control.Button
        searchinteractionLabel          matlab.ui.control.Label
        DiffPanel                       matlab.ui.container.Panel
        ShowDiffPreview                 matlab.ui.control.CheckBox
        typeDropDown                    matlab.ui.control.DropDown
        ExportButton                    matlab.ui.control.Button
        PreviewDiffButton               matlab.ui.control.Button
        RunDifferentialButton           matlab.ui.control.Button
        ZthreshSpinner                  matlab.ui.control.Spinner
        ZthreshSpinnerLabel             matlab.ui.control.Label
        usePlottingtabtoselectsamplesLabel  matlab.ui.control.Label
        Tree_2                          matlab.ui.container.CheckBoxTree
        SelectGeneSetdatabaseNode       matlab.ui.container.TreeNode
        Reactome_Pathways_2024Node      matlab.ui.container.TreeNode
        KEGG_2026Node                   matlab.ui.container.TreeNode
        Elsevier_Pathway_CollectionNode  matlab.ui.container.TreeNode
        MSigDB_Hallmark_2020Node        matlab.ui.container.TreeNode
        MSigDB_Oncogenic_SignaturesNode  matlab.ui.container.TreeNode
        WikiPathways_2024_HumanNode     matlab.ui.container.TreeNode
        WikiPathways_2024_MouseNode     matlab.ui.container.TreeNode
        GO_Biological_Process_2025Node  matlab.ui.container.TreeNode
        GO_Molecular_Function_2025Node  matlab.ui.container.TreeNode
        GO_Cellular_Component_2025Node  matlab.ui.container.TreeNode
        BioPlanet_2019Node              matlab.ui.container.TreeNode
        BioCarta_2016Node               matlab.ui.container.TreeNode
        Panther_2016Node                matlab.ui.container.TreeNode
        searchTextArea                  matlab.ui.control.TextArea
        PreviewExpButton                matlab.ui.control.Button
        normalizeCheckBox               matlab.ui.control.CheckBox
        PreviewloadButton               matlab.ui.control.Button
        Option2Label                    matlab.ui.control.Label
        barcodesButton                  matlab.ui.control.Button
        featuresButton                  matlab.ui.control.Button
        MTXfileButton                   matlab.ui.control.Button
        Option1Label                    matlab.ui.control.Label
        ALLinOneButton                  matlab.ui.control.Button
        InteractionforGSEAanalysisListBox  matlab.ui.control.ListBox
        InteractionforEnrichranalysisListBoxLabel  matlab.ui.control.Label
        LoadexpressionLabel             matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadmetadataButton
        function LoadmetadataButtonPushed(app, event)
    %% Reset
    app.PreviewButton.Enable=false;
    app.AssignButton.Enable=false;
    app.autosaveCheckBox.Enable=false;
    app.StartClusToRaButton.Enable=false;
    app.ColumnsListBox.Items={''};
    app.SelectedFilePath.Value={''};
    app.barcodescolumn.Value={''};
    CheckboxesInput={'app.Xcoordinput';...
         'app.Ycoordinput';...
         'app.CelltypeLevel1input';...
         'app.CelltypeLevel2input';...
         'app.SamplesLevel1input';...
         'app.SamplesLevel2input'};
            for i=1:numel(CheckboxesInput)
eval(char(CheckboxesInput{i} + ".Value='';"))
            end
%% select metadata file and load it
            [file,location] = uigetfile('*','Select file to load in ClusToRa');
try [~, ~, ext] = fileparts(file);catch, end
% hfig = uifigure('Name','File loading progress','WindowStyle','modal');
% hfig.Position=[hfig.Position(1:2),hfig.Position(3)*.75,hfig.Position(4)/2];

    %%%%file selected sanity test
if isequal(file,0)
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User canceled selection','Reading file error', opts);
    return
elseif ~ismember(ext,{'.txt';'.csv';'.tsv';'.xlsx';'.xls'}) 
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Invalid file type! Supported file types: *.txt, *.csv, *.tsv, *.xlsx, *.xls','Reading file error', opts);
    return
else
h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message =['Please wait while I read and prepare the selected file ( ' file ' )'];

        if strcmp(ext,'.tsv')
            metadata=readtable(fullfile(location,file),'FileType','text','VariableNamingRule','preserve');
        else
opts = detectImportOptions(fullfile(location,file));
opts.VariableNamesLine=1;
opts.VariableNamingRule='preserve'; 
if isempty(app.delimiterDropDown.UserData) | strcmp(app.delimiterDropDown.UserData,'Auto')
    app.delimiterDropDown.UserData='Auto';
else
    app.delimiterDropDown.UserData
    opts.Delimiter=app.delimiterDropDown.UserData;
end
if app.advancedDropDown.ValueIndex==2
    opts = setvartype(opts, opts.SelectedVariableNames{1}, 'string');
    format long g
end
            metadata=readtable(fullfile(location,file),opts);
        end
pause(2)
close(h)
% update column list
app.ColumnsListBox.Items=metadata.Properties.VariableNames';

  app.SelectedFilePath.Value=fullfile(location,file);
  app.LoadmetadataButton.UserData=metadata;

%% Enable the ones reset
app.PreviewButton.Enable=true;
app.AssignButton.Enable=true;
app.ClearButton.Enable=true;
app.autosaveCheckBox.Enable=true;
end

        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            
            if app.metaCheckBox.Value %preview table
                fig = uifigure;
            gl = uigridlayout(fig, [1 1]); % 1 row, 1 column;
uitable(gl, 'Data', app.LoadmetadataButton.UserData);%, 'Position', [20 20 500 300]
            elseif app.xygCheckBox.Value & ~isempty(app.StartClusToRaButton.UserData) % preview groupd spatial
                h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Preparing preview...','Indeterminate','on');
idx=app.StartClusToRaButton.UserData;
xy=table2array(app.LoadmetadataButton.UserData(:,idx(1:2)));%spatial
g=table2cell(app.LoadmetadataButton.UserData(:,idx(3))); %cell types groups
%% Scatter Plots

IMG.img={};
if isempty(idx{5}) & isempty(idx{6})
    fig=figure('Visible','off');haxes=axes(fig);
    gscatter(haxes,xy(:,1),xy(:,2),g,[],[],1,'off')
    daspect([1 1 1]),box off, axis off tight
    img = getframe(haxes); close(fig)
    IMG.img{1}=img.cdata;
    IMG.title{1}=strrep(idx(3),'_','-');
elseif ~isempty(idx{5}) & isempty(idx{6})
    group1=unique(table2cell(app.LoadmetadataButton.UserData(:,idx{5})));
    for i=1:numel(group1)
        idxG=ismember(table2cell(app.LoadmetadataButton.UserData(:,idx{5})),group1(i));
fig=figure('Visible','off');haxes=axes(fig);
    gscatter(haxes,xy(idxG,1),xy(idxG,2),g(idxG),[],[],1,'off')
    daspect([1 1 1]),box off, axis off tight
    img = getframe(haxes); close(fig)
    IMG.img{i}=img.cdata;
    IMG.title{i}=strrep(group1(i),'_','-');        
    end

elseif ~isempty(idx{5}) & ~isempty(idx{6})
     group1=unique(table2cell(app.LoadmetadataButton.UserData(:,idx{5})));
     group2=unique(table2cell(app.LoadmetadataButton.UserData(:,idx{6})));
     k=0;
    for i=1:numel(group1)
        for j=1:numel(group2)
        idxG=ismember(table2cell(app.LoadmetadataButton.UserData(:,idx{5})),group1(i)) &...
            ismember(table2cell(app.LoadmetadataButton.UserData(:,idx{6})),group2(j));
        if sum(idxG)>0
            k=k+1;
fig=figure('Visible','off');haxes=axes(fig);
    gscatter(haxes,xy(idxG,1),xy(idxG,2),g(idxG),[],[],1,'off')
    daspect([1 1 1]),box off, axis off tight
    img = getframe(haxes); close(fig)
    IMG.img{k}=img.cdata;
    IMG.title{k}=strrep(join([group1(i) group2(j)], ':-'),'_','-');  
        end
        end
    end
end

N = numel(IMG.img); % Total plots
ratio = 16/9; 
%layout for a widescreen
nCol = ceil(sqrt(N * ratio));
nRow = ceil(N / nCol);
set(0, 'DefaultFigureWindowStyle', 'normal');
fig = figure;%fig.WindowState = 'maximized';
for i=1:N
subplot(nRow,nCol,i); imshow(IMG.img{i}); title(IMG.title{i})
end
img=getframe(fig);
 app.PreviewButton.UserData=img.cdata;
 close(h)
            else %from previous run of loaded data
try 
    ALL=app.SaveButton.UserData;
    set(0, 'DefaultFigureWindowStyle', 'normal');
    fig = figure;%fig.WindowState = 'maximized';
    imshow(ALL.image);
catch
end

            end
            
        end

        % Value changed function: randomSpinner
        function randomSpinnerValueChanged(app, event)
            % value = app.randomSpinner.Value;
% if value<1000, app.randomSpinner.FontColor='r'; else, app.randomSpinner.FontColor='k';end
        end

        % Value changed function: YcoordCheckBox
        function YcoordCheckBoxValueChanged(app, event)
if app.YcoordCheckBox.Value; ClusToRa_GUI_selection(app,2); end
            
        end

        % Value changed function: XcoordCheckBox
        function XcoordCheckBoxValueChanged(app, event)
if app.XcoordCheckBox.Value; ClusToRa_GUI_selection(app,1); end
            
        end

        % Value changed function: CelltypeLevel1CheckBox
        function CelltypeLevel1CheckBoxValueChanged(app, event)
if app.CelltypeLevel1CheckBox.Value; ClusToRa_GUI_selection(app,3); end
            
        end

        % Value changed function: CelltypeLevel2CheckBox
        function CelltypeLevel2CheckBoxValueChanged(app, event)
if app.CelltypeLevel2CheckBox.Value & ~isempty(app.LoadmetadataButton.UserData) 
    ClusToRa_GUI_selection(app,4); 
elseif app.CelltypeLevel2CheckBox.Value & isempty(app.LoadmetadataButton.UserData) & ~isempty(app.SaveButton.UserData) 
    [file,location] = uigetfile('*.txt','Select text file with Cell tyle Level 1 and 2');
    %%%%file selected sanity test
if isequal(file,0)
    app.CelltypeLevel2CheckBox.Value=false;
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User canceled selection','Reading file error', opts);
    return
else
    app.CelltypeLevel2CheckBox.Value=false;
CellTypes2=readcell(fullfile(location,file))    ;   
    if size(CellTypes2,2)<2 
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Invalid file size! Import file must have 2 or more columns','Invalid file error', opts);
    return
    end
% app.CelltypeLevel2input.UserData=CellTypes2;
app.SaveButton.UserData.CellTypes2=sortrows(CellTypes2,[2,1]);
end
end
            
        end

        % Value changed function: SamplesLevel1CheckBox
        function SamplesLevel1CheckBoxValueChanged(app, event)
if app.SamplesLevel1CheckBox.Value; ClusToRa_GUI_selection(app,5); end
            
        end

        % Value changed function: SamplesLevel2CheckBox
        function SamplesLevel2CheckBoxValueChanged(app, event)
if app.SamplesLevel2CheckBox.Value; ClusToRa_GUI_selection(app,6); end
            
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
ClusToRa_GUI_selection(app,'plus')
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
ClusToRa_GUI_selection(app,'minus')
        end

        % Value changed function: epsilonDropDown
        function epsilonDropDownValueChanged(app, event)
            if strcmp(app.epsilonDropDown.Value,'User')
fig = uifigure("Position",[100 200 100 171]);
numField = uieditfield(fig, 'numeric', 'Position', [10 80 75 20]);
uibutton(fig, 'Text', 'Return Value', ...
    'Position', [10 50 80 20], ...
    'ButtonPushedFcn', @(btn,event) uiresume(fig));
uiwait(fig);
%Check if figure still exists (in case user clicked "X")
if isvalid(fig)
    % NOW grab the number from the field
    inputValue = numField.Value; 
    if inputValue <= 0
        app.epsilonDropDown.Value = 'Auto';
    else
        app.epsilonUser.Value = inputValue;
    end
    close(fig);
end
            else
                app.epsilonUser.Value = [];
            end
assignin("base","app",app)
        end

        % Button pushed function: StartClusToRaButton
        function StartClusToRaButtonPushed(app, event)
            %% Clustora Analysis
h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Clustora Analysis, see progress bar...','Indeterminate','on');
  %initial sanity check
            ALL.image=app.PreviewButton.UserData;
if isempty(ALL.image)
selection = uiconfirm(app.UIFigure, ...
    'User did not preview groups selected spatial images! This is important for ClusToRa analysis! Do you wish to continue?', 'Execution Decision', ...
    'Options', {'Ignore and continue', 'Stop ClusToRa Analysis'}, ...
    'DefaultOption', 2);
% Handle the response
if strcmp(selection, 'Stop ClusToRa Analysis')
    close(h)
    app.xygCheckBox.Value=true;
    app.metaCheckBox.Value=false;
    return; % Stops the current execution
end
end
h.Message='Setup';
            %%%%prevent reclick
app.StartClusToRaButton.Enable=false;
app.progressGauge.Value = 0;
app.SaveButton.Enable=false;
app.Level1ListBox.Items={''};
app.Level2ListBox.Items={''};
app.importButton.BackgroundColor=[0.96,0.96,0.96];


  %%% Get data
  metadata=app.LoadmetadataButton.UserData;
  idx=app.StartClusToRaButton.UserData;
  idx=[idx;app.barcodescolumn.Value];
  ALL.idx=idx;%save columns used

% store celltype level 2 if provided
if isempty(app.CelltypeLevel2input.Value{1})
    ALL.CellTypes2=[];
else
   data= metadata(:,[app.CelltypeLevel1input.Value,app.CelltypeLevel2input.Value]);
[~,ia,~]=unique(table2cell(data(:,1)));
ALL.CellTypes2= sortrows(table2cell(data(ia,:)),[2,1]);  
   % app.SaveButton.UserData.CellTypes2=sortrows(CellTypes2,[2,1]);
end
  %% define parameters
  CheckboxesInput={'app.Xcoordinput';...
         'app.Ycoordinput';...
         'app.CelltypeLevel1input';...
         'app.CelltypeLevel2input';...
         'app.SamplesLevel1input';...
         'app.SamplesLevel2input'};
  if strcmp(app.epsilonDropDown.Value,'User')
      epsilon=app.epsilonUser.Value;
  else
      epsilon=[];
  end
  %keep params for future loading of results
  params.epsilon=epsilon; 
  ncells=app.mincellsSpinner.Value;params.ncells=ncells;
  Nrandom=app.randomSpinner.Value;params.Nrandom=Nrandom;
  Threads=app.ThreadsSpinner.Value;params.Threads=Threads;
  SelfColoc=app.selfCheckBox.Value;params.SelfColoc=SelfColoc;
  Clumpiness=app.clumpinessCheckBox.Value;params.Clumpiness=Clumpiness;
ALL.params=params;
  %%analysis
  Tier = struct();

  [~,locb]=ismember(idx(1:3),metadata.Properties.VariableNames);locb(locb==0)=[];
  if size(locb,2)>size(locb,1), locb=locb';end
  %%%% save barcodes if loaded
  if ~isempty(idx{7})
% Barcodes=metadata(:,[locb;find(ismember(metadata.Properties.VariableNames,idx{7}))]); %store barcodes for use later in Differential analysis
Barcodes=table2cell(metadata(:,idx{7})); %store barcodes for use later in Differential analysis
Barcodes=cellfun(@num2str,Barcodes(:,1),'UniformOutput',false);%convert 2 str just incase it's double
% if strcmp(Barcodes.Properties.VariableTypes,'double')
% Barcodes=cellfun(@num2str,num2cell(table2array(Barcodes)),'UniformOutput',false);
% else
% Barcodes=table2cell(Barcodes);
% end
  ALL.params.barcodes=Barcodes;
  else
      Barcodes=[];
      ALL.params.barcodes=Barcodes;
  end
  
  ALL.Diff={};
  h.Message='Clustora Analysis, see progress bar...';
%% Whole table, no tiers
if isempty(idx{5})
    app.Lamp.Color = [1 0 0];
app.progressGauge.Value = 50;%(currentStep / totalSteps) * 100
h.Message=['Clustora Analysis, see progress bar...(' num2str(1) '/' num2str(1) ')'];

[~,  Group1, ~] = fileparts(app.SelectedFilePath.Value);
validName = matlab.lang.makeValidName(Group1{1});
Group1{1,2}=validName;
    [Results,params]=ClusToRa(metadata(:,locb),epsilon,ncells,Nrandom,Threads,SelfColoc);
if ~isempty(Barcodes),params.barcodes=Barcodes(:,end);end
    Tier.(validName).Results=Results;
    Tier.(validName).params=params;
app.progressGauge.Value = 100;

ALL.Results=Tier;
ALL.Group1=Group1;
ALL.Diff=[ALL.Diff;{validName}];% for Diff IDs
% store for plotting
CellTypes=params.iG;
cMaxChord=[max(abs(cell2mat(Results.Zscores(2:end,2:end))),[],'all'),... cMax for Z scors & FC
      max(abs(cell2mat(Results.ClustInOut(2:end,2:end))),[],'all')];
ALL.CellTypes=CellTypes;
ALL.cMax=cMaxChord;
app.Lamp.Color = [0.85 0.85 0.85];
  
app.ToplotpreviewDropDown.Items={'Level1'};%one level 
%% One level Tier analysis
elseif ~isempty(idx{5}) & isempty(idx{6}) 
    % eval(['value=' CheckboxesInput{5} '.Value;'])
    value=eval([CheckboxesInput{5} '.Value']);
    Group1=unique(table2cell(metadata(:,value{1})),'stable');
    
    CellTypes={};
    cMaxChord=[];
for i = 1:size(Group1,1)
    app.Lamp.Color = [1 0 0];
app.progressGauge.Value = (i/(size(Group1,1)+1))* 100;%(currentStep / totalSteps) * 100
h.Message=['Clustora Analysis, see progress bar...(' num2str(i) '/' num2str(size(Group1,1)) ')'];
    % Clean the name so it's a valid MATLAB identifier
    validName = matlab.lang.makeValidName(Group1{i,1});
    % Assign to the structure
    Group1{i,2}=validName;
    idx=ismember(table2cell(metadata(:,value{1})),Group1{i,1});
        if sum(idx)>0
    [Results,params]=ClusToRa(metadata(idx,locb),epsilon,ncells,Nrandom,Threads,SelfColoc);
    if ~isempty(Barcodes),params.barcodes=Barcodes(idx,end);end
    Tier.(validName).Results = Results; 
    Tier.(validName).params = params;
    ALL.Diff=[ALL.Diff;{validName}];
CellTypes=[CellTypes;params.iG];
cMaxChord=[cMaxChord;[max(abs(cell2mat(Results.Zscores(2:end,2:end))),[],'all'),... cMax for Z scors & FC
            max(abs(cell2mat(Results.ClustInOut(2:end,2:end))),[],'all')]];
        end
        app.Lamp.Color = [0.85 0.85 0.85];
end
app.progressGauge.Value = 100;

ALL.Results=Tier;
ALL.Group1=Group1;
ALL.Group2=[];
% store for plotting
CellTypes=unique(CellTypes);
% cMax=max(cMax);
ALL.CellTypes=CellTypes;
ALL.cMax=cMaxChord;

app.ToplotpreviewDropDown.Items={'Level1'};%one level
%% Two level Tier analysis
elseif ~isempty(idx{5}) & ~isempty(idx{6}) 
% eval(['value1=' CheckboxesInput{5} '.Value;'])
value1=eval([CheckboxesInput{5} '.Value']);
Group1=unique(table2cell(metadata(:,value1{1})),'stable');

% eval(['value2=' CheckboxesInput{6} '.Value;'])
value2=eval([CheckboxesInput{6} '.Value']);
Group2=unique(table2cell(metadata(:,value2{1})),'stable');

ii=numel(unique(join(table2cell(metadata(:,[value1(1),value2(1)])))));
jj=0;

    CellTypes={};
    cMaxChord=[];
   for i = 1:size(Group1,1)
        cMax2=[];
    validName1 = matlab.lang.makeValidName(Group1{i,1});
    Group1{i,2}=validName1;
    for j = 1:size(Group2,1) 
        app.Lamp.Color = [1 0 0];
    validName2 = matlab.lang.makeValidName(Group2{j,1});
    Group2{j,2}=validName2;

    idx=ismember(table2cell(metadata(:,value1{1})),Group1{i,1}) & ...
        ismember(table2cell(metadata(:,value2{1})),Group2{j,1}) ;
        if sum(idx)>0
            disp(['ClusToRa Analysis of [' Group1{i,1} ' --- ' Group2{j,1} ']'])
            jj=jj+1;        
app.progressGauge.Value = (jj/(ii+1))* 100;%(currentStep / totalSteps) * 100
h.Message=['Clustora Analysis, see progress bar...(' num2str(jj) '/' num2str(ii) ')'];
    [Results,params]=ClusToRa(metadata(idx,locb),epsilon,ncells,Nrandom,Threads,SelfColoc,Clumpiness);
    if ~isempty(Barcodes),params.barcodes=Barcodes(idx,end);end
    Tier.(validName1).(validName2).Results = Results; 
    Tier.(validName1).(validName2).params = params; 
    ALL.Diff=[ALL.Diff;{validName1,validName2}];

CellTypes=[CellTypes;params.iG];
cMax2=[cMax2;[max(abs(cell2mat(Results.Zscores(2:end,2:end))),[],'all'),... cMax for Z scors & FC
            max(abs(cell2mat(Results.ClustInOut(2:end,2:end))),[],'all')]];

        end
        app.Lamp.Color = [0.85 0.85 0.85];
    end
    cMaxChord=[cMaxChord;max(cMax2)];
   end 
app.progressGauge.Value = 100;

ALL.Results=Tier;
ALL.Group1=Group1; ALL.Group2=Group2;
% store for plotting
CellTypes=unique(CellTypes);
% cMax=max(cMax);
ALL.CellTypes=CellTypes;
ALL.cMax=cMaxChord;

app.ToplotpreviewDropDown.Items={'Level1','Level2'};% two levels
end

app.SaveButton.UserData=ALL;
h.Message='Almost!...';
%% Save results
if app.autosaveCheckBox.Value
% [~,  VarName, ~] = fileparts(app.SelectedFilePath.Value);
% VarName=[VarName{1} '_' char(datetime('now','Format','uuuu-MM-dd_HH_mm')) '.mat'];
% save(VarName,'ALL')
SaveButtonPushed(app, event)
else
    app.SaveButton.Enable=true;
end
%%%reactivate button
app.StartClusToRaButton.Enable=true;
app.SwitchLoad.Value='loaded';
% assignin("base","Tier",Tier) 
h.Message='Done!'; pause(1), close(h)
        end

        % Value changed function: autosaveCheckBox
        function autosaveCheckBoxValueChanged(app, event)
        if app.autosaveCheckBox.Value
    [path,VarName,~] = fileparts(app.SelectedFilePath.Value);
    app.SaveButton.Enable=false;
    folderPath = uigetdir(path{1},'Select folder where results will be saved'); 
if isequal(folderPath, 0)
        opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User cancelled folder selection!','Folder selection error', opts);
   app.autosaveCheckBox.Value=false;
   app.SaveButton.Enable=true;
else
    path={}; 
    path{1}=folderPath;
    path{2}=VarName{1};
   app.autosaveCheckBox.UserData=path;
end
        else
            if ~isempty(app.SaveButton.UserData), app.SaveButton.Enable=true; end
        end         
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message ='Please wait while I read save your results';

ALL=app.SaveButton.UserData;
ClusToRaDIFF=app.RunDifferentialButton.UserData;

if app.autosaveCheckBox.Value
    path=app.autosaveCheckBox.UserData;
    folderPath=path{1};
    VarName=path{2};
else
        [path,  VarName, ~] = fileparts(app.SelectedFilePath.Value);
        VarName=VarName{1};
folderPath = uigetdir(path{1},'Select folder where to save results'); 
end

h.Message =['Please wait while I save your results file ( ClusToRa_MAIN_' VarName ' )'];
VarName=['ClusToRa_MAIN_' VarName '_' char(datetime('now','Format','uuuu-MM-dd_HH_mm')) '.mat'];

save([folderPath filesep VarName],'ALL','ClusToRaDIFF')   
%% Create table
nLevels=size(ALL.Diff,2);
iSamples=size(ALL.Diff,1);

ResultsSummary={};% Col1 PercentInCluster and Clo2 CEI (clumpiness) if available

for i=1:iSamples
  if nLevels==2
Zscores=ALL.Results.(ALL.Diff{i,1}).(ALL.Diff{i,2}).Results.Zscores;
ClustInOut=ALL.Results.(ALL.Diff{i,1}).(ALL.Diff{i,2}).Results.ClustInOut;
  elseif nLevels==1
Zscores=ALL.Results.(ALL.Diff{i,1}).Results.Zscores;
ClustInOut=ALL.Results.(ALL.Diff{i,1}).Results.ClustInOut;
  end
Zscores(1)={'Zscores'};
ClustInOut(1)={'Log2FC(Infiltration/Envelopment'};

data=Zscores(2:end,2:end); data(cellfun(@isnan,data))={[]};
Zscores(2:end,2:end)=data;

data=ClustInOut(2:end,2:end); data(cellfun(@isnan,data))={[]};
ClustInOut(2:end,2:end)=data;

ResultsSummary(end+2,1)=join(ALL.Diff(i,:),'-:-');
ResultsSummary(end+1:end+size(Zscores,1)*2,1:size(Zscores,2))=[Zscores;ClustInOut];
end
ResultsSummary(1)={'Zscores & Log2FC(Infiltration/Envelopment'};
ClusToRa_showTable(cell2table(ResultsSummary),'Zscores and Log2FC-Infiltration-Envelopment')
%%
close(h)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
[file,location] = uigetfile('*.mat','Select ClusToRa analysis file to load'); 
try [~, ~, ext] = fileparts(file);catch, end
% hfig = uifigure('Name','Clustora results loading progress','WindowStyle','modal');
% hfig.Position=[hfig.Position(1:2),hfig.Position(3)*.75,hfig.Position(4)/2];

    %%%%file selected sanity test
if isequal(file,0)
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User canceled selection','Reading file error', opts);
    return
elseif ~strcmp(ext,'.mat') 
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Invalid file type! Supported file types: *.mat','Reading file error', opts);
    return
else

h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message =['Please wait while I read and prepare the selected file ( ' file ' )'];
load(fullfile(location,file),'ALL','ClusToRaDIFF')

app.SaveButton.UserData=ALL;
app.RunDifferentialButton.UserData=ClusToRaDIFF;
app.SwitchLoad.Value='loaded';
%% Restore settings
app.ThreadsSpinner.Value=maxNumCompThreads;
pause(2)
close(h)

end
        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            % set(gcf, 'WindowStyle', 'docked')
            if strcmp(app.SwitchLoad.Value,'drive')
LoadButtonPushed(app, event)
ALL=app.SaveButton.UserData; 
            else
ALL=app.SaveButton.UserData;                 
            end

if ~isempty(ALL)
            Fields = fieldnames(ALL.Results);
[~,locb]=ismember(Fields,ALL.Group1(:,2));locb(locb==0)=[];
app.Level1ListBox.ItemsData=Fields;
app.Level1ListBox.Items=ALL.Group1(locb,1);

% update Tree Nodes
collapse(app.SelectCellsToScatterPlotNode)
delete(app.SelectCellsToScatterPlotNode.Children);
    for j = 1:length(ALL.CellTypes)
        uitreenode(app.SelectCellsToScatterPlotNode, 'Text', ALL.CellTypes{j});
    end
expand(app.SelectCellsToScatterPlotNode);
 
%%% Enable plotting and populate options
app.CellTypePreviewButton.Enable=true;
app.ChordplotparametersPanel.Enable=true;
app.ScatterparametersPanel.Enable=true;
app.ClusterSummary.Enable=true;
app.importButton.BackgroundColor=[1,.85,1];
Level1ListBoxValueChanged(app, event)
%             if ~isempty(Barcodes)
% app.DiffSetupPanel.Enable=true;
%             end
        if ~isempty(ALL.params.barcodes)
            app.DiffSetupPanel.Enable=true;
        end
        if ~isempty(app.RunDifferentialButton.UserData)
            app.DiffSetupPanel.Enable=true;
            app.GeneAnalysisButton.Enable=true;
            app.PreviewDiffButton.Enable=true;
            app.ExportButton.Enable=true;
            if ~isempty(app.RunDifferentialButton.UserData)
            app.RunDifferentialButton.Enable=false;
            end
        end
else
    app.importButton.BackgroundColor=[0.96,0.96,0.96];
    app.SwitchLoad.Value='drive';
    app.PlotChordButton.Enable=false;
    app.ChordplotparametersPanel.Enable=false;
    app.ClusterSummary.Enable=false;
    app.ScatterparametersPanel.Enable=false;
    app.CellTypePreviewButton.Enable=false;
    app.ColocMatrixButton.Enable=false;
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} No ClusToRa results detected','ClusToRa results error', opts);
end

%%%%
if strcmp(app.ToplotpreviewDropDown.Value,'Level1') & numel(app.ToplotpreviewDropDown.Items)==2
    app.ColocMatrixButton.Enable=false;
else
    app.ColocMatrixButton.Enable=true;
end
        end

        % Value changed function: Level1ListBox
        function Level1ListBoxValueChanged(app, event)
ALL=app.SaveButton.UserData;  
% try ClusToRaDIFF=app.RunDifferentialButton.UserData; catch,end%If Diff exist

if strcmp(app.ToplotpreviewDropDown.Value,'Level1') & numel(app.ToplotpreviewDropDown.Items)==2
    app.ColocMatrixButton.Enable=false;
else
    app.ColocMatrixButton.Enable=true;
end

value = app.Level1ListBox.ItemsData{app.Level1ListBox.ValueIndex};
try
            Fields = fieldnames(ALL.Results.(value));
[~,locb]=ismember(Fields,ALL.Group2(:,2));locb(locb==0)=[];
app.Level2ListBox.ItemsData=Fields;
app.Level2ListBox.Items=ALL.Group2(locb,1);  

app.ToplotpreviewDropDown.Items={'Level1','Level2'};
app.PlotChordButton.Enable=true;

%%
catch %i.e No level2
    app.ToplotpreviewDropDown.Items={'Level1'}; 
    app.PlotChordButton.Enable=true;
    if app.Label_1.Visible || app.Label_2.Visible || app.Label_3.Visible || app.Label_4.Visible
    app.PlotScatterButton.Enable=true;
    end

        %%
end

%% Diif if exist
try 
    ClusToRaDIFF=app.RunDifferentialButton.UserData; %If Diff exist

    % One level loaded only
    if ClusToRaDIFF.nLevels==1
GSEAgroups=ClusToRaDIFF.(value).colocGroups;
app.InteractionforGSEAanalysisListBox.Items=sort(GSEAgroups);
app.pairsTextArea.Value=num2str(numel(app.InteractionforGSEAanalysisListBox.Items));
% app.Diff_selected.Value=[value ':- ' (app.Level2ListBox.ItemsData{app.Level2ListBox.ValueIndex})];
app.Diff_selected.Value=value;

% Single in groups
    elseif (ClusToRaDIFF.nLevels==2 & strcmp(app.ToplotpreviewDropDown.Value,'Level2')) && isscalar(app.Level2ListBox.ValueIndex)
GSEAgroups=ClusToRaDIFF.(value).(app.Level2ListBox.ItemsData{app.Level2ListBox.ValueIndex}).colocGroups;
app.InteractionforGSEAanalysisListBox.Items=sort(GSEAgroups);
app.pairsTextArea.Value=num2str(numel(app.InteractionforGSEAanalysisListBox.Items));
app.Diff_selected.Value=[value ':- ' (app.Level2ListBox.ItemsData{app.Level2ListBox.ValueIndex})];

%combined groups
    elseif ClusToRaDIFF.nLevels==2 & strcmp(app.ToplotpreviewDropDown.Value,'Level1') 
%         GSEAgroups={};
%         for i=1:numel(app.Level2ListBox.Items)
% GSEAgroups=[ClusToRaDIFF.(value).(app.Level2ListBox.ItemsData{i}).colocGroups;GSEAgroups];
%         end
%         app.InteractionforGSEAanalysisListBox.Items=unique(GSEAgroups);
%use diff integration method instead
% get ForDEGs
app.ShowDiffPreview.Value=false;app.PreviewDiffButton.Enable=false;
PreviewDiffButtonPushed(app, event)% Compute DEGs 
DEGs=app.PreviewDiffButton.UserData;
app.InteractionforGSEAanalysisListBox.Items=strrep(DEGs.ForDEGs(1,2:3:end),' Log2FC','');
        app.pairsTextArea.Value=num2str(numel(app.InteractionforGSEAanalysisListBox.Items));
        app.Diff_selected.Value=[value ':- combined'];
    else
app.InteractionforGSEAanalysisListBox.Items={''};
app.pairsTextArea.Value={''};
app.Diff_selected.Value={''};        
    end
catch
end
%% Setup
try
cla(app.ColorMap); app.ColorMap.Position=[170,48,60,20]; axis(app.ColorMap, 'off'); 
app.ColorMap.InnerPosition = app.ColorMap.Position;
app.ColormapDropDown.Items=[{'ClusToRa_colormap'};cellstr(colormaplist)];
ColormapDropDownValueChanged(app, event)
catch %ME, add catch because of standallone axis off not supported in some standalones
%                     opts = struct('WindowStyle','modal','Interpreter','tex');
% warndlg(['\color{blue} '  ME.message '(line ' num2str(ME.stack(1).line) ')'],'error', opts);
end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
           try
               cla(app.ColorMap);  app.ColorMap.Position=[170,48,60,20]; axis(app.ColorMap, 'off'); 
            value = app.ColormapDropDown.Value;
gradientData = linspace(0, 1, 256); % Create a 1x256 horizontal gradient data
imagesc(app.ColorMap, gradientData);% Display as an image in the specific axes
cmap=eval([value '(256)']);
colormap(app.ColorMap, cmap); % Apply the desired colormap to that axes ONLY
           catch %ME, add catch because of standallone axis off not supported in some standalones
%                 opts = struct('WindowStyle','modal','Interpreter','tex');
% warndlg(['\color{blue} '  ME.message '(line ' num2str(ME.stack(1).line) ')'],'error', opts);
           end
            
        end

        % Value changed function: scoreDropDown
        function scoreDropDownValueChanged(app, event)
if app.scoreDropDown.ValueIndex==1 % Zscores
app.ThreshSpinner.Limits=[1.96,Inf]; app.ThreshSpinner.Value=1.96;
elseif app.scoreDropDown.ValueIndex==2 % FC
    app.ThreshSpinner.Limits=[0,Inf]; app.ThreshSpinner.Value=0;
end
            
        end

        % Button pushed function: PlotChordButton
        function PlotChordButtonPushed(app, event)
% %
% hfig = uifigure('Name','Chord plotting'); hfig.Position(4)= hfig.Position(4)*.6;%,'WindowStyle','modal'
try
h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Chord plotting in progress...','Indeterminate','on');
catch
h=uiprogressdlg(app.UIFigure,'Title','Progress...','Indeterminate','on');
end
app.PlotChordButton.Enable=false;
%% set parameters
ScoreID=app.scoreDropDown.ValueIndex;
Thresh=app.ThreshSpinner.Value;
cmap=app.ColormapDropDown.Value;
minN=app.minsampleSpinner.Value;

if ScoreID==1
    T{1}='ClusTora co-localization Z-scores'; 
    T{2}='ClusToRa Z-scores';
elseif ScoreID==2
    T{1}='Infiltration/Envelopment ratio'; 
    T{2}='Log_2FC';   
end
alphaVal=app.alphaSpinner.Value;

SelfColoc=app.selfCheckBox.Value;

%% get data and plot
ALL=app.SaveButton.UserData;  
Tier=ALL.Results;
% cMax=ALL.cMax;
CellTypes=ALL.CellTypes;
%% Sort celltypes if defined in level 2
if ~isempty(ALL.CellTypes2)
    [lia,locb]=ismember(ALL.CellTypes2(:,1),CellTypes);
    if sum(~lia)==0 % make sure all matched, if not, omit
    CellTypes=CellTypes(locb);
    end
end

%%%% let's do custom colors and sort by predefined color order MAY2026
if app.colorsDropDown.ValueIndex==1 || app.colorsDropDown.ValueIndex==2
colors=[];
else
data=app.colorsDropDown.UserData; % col1 & 2 text, 3:5 rgb 
%sort cells by
    [lia,locb]=ismember(data(:,1),CellTypes);
    if sum(~lia)==0 % make sure all matched, if not, omit
    CellTypes=CellTypes(locb);
    colors=cell2mat(data(:,2:4));
    else
        colors=[];
    end

end
%%
            try
     %% Plot merged group
if strcmp(app.ToplotpreviewDropDown.Value,'Level1')

    app.Level2ListBox.Enable=false;
    data=nan(numel(CellTypes),numel(CellTypes),numel(app.Level2ListBox.Items));

validName1=app.Level1ListBox.ItemsData{app.Level1ListBox.ValueIndex}; 
T{1}=strrep([T{1} ' (' app.Level1ListBox.Items{app.Level1ListBox.ValueIndex} ')'],'_','-');   
            
if isempty(app.Level2ListBox.Items{1})
    if ScoreID==1
data=Tier.(validName1).Results.Zscores;
    elseif ScoreID==2
data=Tier.(validName1).Results.ClustInOut;
    end
    [~,locb]=ismember(CellTypes,data(:,1)); locb(locb==0)=[];
data=cell2mat(data(locb,locb));
data=[[{''};CellTypes],[CellTypes';num2cell(data)]];

else
            for i=1:numel(app.Level2ListBox.Items)
validName2=app.Level2ListBox.ItemsData{i};
    if ScoreID==1
data2=Tier.(validName1).(validName2).Results.Zscores;
    elseif ScoreID==2
data2=Tier.(validName1).(validName2).Results.ClustInOut;
    end
%%%% align with cell types
[lia,locb]=ismember(CellTypes,data2(:,1)); locb(locb==0)=[];
data(lia,lia,i)=cell2mat(data2(locb,locb));
            end
                idxmin=sum(~isnan(data),3)>=round(minN*numel(app.Level2ListBox.Items)/100);% find ones without minimum occurences
    data=mean(data,3,'omitmissing');% mean of those values
    data(~idxmin)=nan;
    data=[[{''};CellTypes],[CellTypes';num2cell(data)]];
end
if app.cMaxChord.ValueIndex==1 
    CMAX=ALL.cMax(app.Level1ListBox.ValueIndex,ScoreID); 
elseif app.cMaxChord.ValueIndex==3
    CMAX=max(ALL.cMax(:,ScoreID)); 
elseif app.cMaxChord.ValueIndex==2
    CMAX=[]; 
elseif app.cMaxChord.ValueIndex==4
        cMaxChordValueChanged(app, event)
    CMAX=app.cMaxChord.UserData;
end
[RGB,~,~]=ClusToRa_chord(data,ScoreID,Thresh,cmap,CMAX,T,alphaVal,colors,SelfColoc);
hfig=gcf; hfig.Name=strrep(T{1},'/','-');

    %% Plot individual Level two selected options
else %% 
validName1=app.Level1ListBox.ItemsData{app.Level1ListBox.ValueIndex};
        for i=app.Level2ListBox.ValueIndex% loop & plot selected options
validName2=app.Level2ListBox.ItemsData{i};
T{1}=strrep([T{1} ' (' app.Level1ListBox.Items{app.Level1ListBox.ValueIndex} ':-' ...
    app.Level2ListBox.Items{i} ')'],'_','-');

    if ScoreID==1
data=Tier.(validName1).(validName2).Results.Zscores;
    elseif ScoreID==2
data=Tier.(validName1).(validName2).Results.ClustInOut;
    end
     [lia,locb]=ismember(CellTypes,data(:,1)); locb(locb==0)=[];
data=cell2mat(data(locb,locb));
data=[[{''};CellTypes(lia)],[CellTypes(lia)';num2cell(data)]];
    if app.cMaxChord.ValueIndex==1
    CMAX=ALL.cMax(app.Level1ListBox.ValueIndex,ScoreID); 
    elseif app.cMaxChord.ValueIndex==3
    CMAX=max(ALL.cMax(:,ScoreID)); 
    elseif app.cMaxChord.ValueIndex==2
    CMAX=[]; 
    elseif app.cMaxChord.ValueIndex==4
        cMaxChordValueChanged(app, event)
    CMAX=app.cMaxChord.UserData;
    end

[RGB,~,~]=ClusToRa_chord(data,ScoreID,Thresh,cmap,CMAX,T,alphaVal,colors,SelfColoc);%
hfig=gcf; hfig.Name=strrep(T{1},'/','-');
        end
end
            catch ME
                app.PlotChordButton.Enable=true;
                % close(hfig)
                opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg(['\color{red} '  ME.message],'ClusToRa plotting error', opts);
            end

% [lgd,BCC] = [RGB,~,~]=ClusToRa_chord(data,ScoreID,Thresh,cmap,cMax,T,alphaVal,colors,SelfColoc)
%%% re-enable
app.PlotChordButton.Enable=true;
app.colorsDropDown.Enable=true;
app.ClustersbarssummaryButton.Enable=true;
ALL.RGB=RGB;%update RGB
app.SaveButton.UserData=ALL;  
close(h)
        end

        % Value changed function: ToplotpreviewDropDown
        function ToplotpreviewDropDownValueChanged(app, event)
if strcmp(app.ToplotpreviewDropDown.Value,'Level1') & numel(app.ToplotpreviewDropDown.Items)==2
    app.Level2ListBox.Enable=false;
    app.minsampleSpinner.Enable=true;
    app.PlotScatterButton.Enable=false;
else
    app.Level2ListBox.Enable=true;
    app.minsampleSpinner.Enable=false;
    if app.Label_1.Visible || app.Label_2.Visible || app.Label_3.Visible || app.Label_4.Visible
    app.PlotScatterButton.Enable=true;
    end
end

if isscalar(app.Level2ListBox.ValueIndex) | strcmp(app.ToplotpreviewDropDown.Value,'Level1')
    app.GeneAnalysisButton.Enable=true;
    app.PreviewDiffButton.Enable=true; 
else
    app.GeneAnalysisButton.Enable=false;
    app.PreviewDiffButton.Enable=false; 
end
app.PlotChordButton.Enable=true;     
Level1ListBoxValueChanged(app, event)% Update DIFF
% %% Update DIFF selection if exist

% try
% ClusToRaDIFF=app.RunDifferentialButton.UserData;
% nLevels=ClusToRaDIFF.nLevels;
%     if strcmp(app.ToplotpreviewDropDown.Value,'Level1') & nLevels==1 || strcmp(app.ToplotpreviewDropDown.Value,'Level2')
% Level1ListBoxValueChanged(app, event)
%     else
% %%% Common in group
% GSEAgroups=ClusToRaDIFF.(value).(app.Level2ListBox.ItemsData{app.Level2ListBox.ValueIndex}).colocGroups;
% app.InteractionforGSEAanalysisListBox.Items=sort(GSEAgroups);
% 
% 
% 
% 
%     end
% catch
% end
        end

        % Value changed function: CelltypeLevel2Options
        function CelltypeLevel2OptionsValueChanged(app, event)
if app.CelltypeLevel2Options.ValueIndex==2
data=unique(table2cell(app.LoadmetadataButton.UserData(:,app.CelltypeLevel1input.Value))) ;
[path,  ~, ~] = fileparts(app.SelectedFilePath.Value);
writecell(data,...
[path{1} filesep 'CellTypesL1_' char(datetime('now','Format','uuuu-MM-dd_HH_mm')) '.txt'],'Delimiter', 'tab');
app.CelltypeLevel2Options.ValueIndex=1;
elseif app.CelltypeLevel2Options.ValueIndex==3

[file,location] = uigetfile('*.txt','Select text file with Cell tyle Level 1 and 2');
    %%%%file selected sanity test
if isequal(file,0)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User canceled selection','Reading file error', opts);
    return
else
data=readcell(fullfile(location,file))    ;   
    if size(data,2)<2 
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Invalid file size! Import file must have 2 or more columns','Invalid file error', opts);
    return
    end
% Add to table
[lia,locb]=ismember(table2cell(app.LoadmetadataButton.UserData(:,app.CelltypeLevel1input.Value)),data(:,1)); locb(locb==0)=[];
app.LoadmetadataButton.UserData(lia,end+1:end+size(data,2)-1)=data(locb,2:end);
Cols=app.LoadmetadataButton.UserData.Properties.VariableNames;
Cols2=Cols(end-(size(data,2)-2):end);
Cols(end-(size(data,2)-2):end)=join([repmat({'CellTypeLevel2'},numel(Cols2),1),Cols2'],'_')';
app.LoadmetadataButton.UserData.Properties.VariableNames=Cols;
app.ColumnsListBox.Items=app.LoadmetadataButton.UserData.Properties.VariableNames';
end

app.CelltypeLevel2Options.ValueIndex=1;
end
            
        end

        % Button pushed function: CellTypePreviewButton
        function CellTypePreviewButtonPushed(app, event)
            % fig = uifigure;
            try
            % gl = uigridlayout(fig, [1 1]); % 1 row, 1 column;
            if isempty(app.SaveButton.UserData.CellTypes2)
% uitable(gl, 'Data', cell2table(app.SaveButton.UserData.CellTypes));%, 'Position', [20 20 500 300]
ClusToRa_showTable(cell2table(app.SaveButton.UserData.CellTypes),'CellTypePreview')

            else
% uitable(gl, 'Data', cell2table(app.SaveButton.UserData.CellTypes2));%, 'Position', [20 20 500 300]
ClusToRa_showTable(cell2table(app.SaveButton.UserData.CellTypes2),'CellTypePreview')


            end
            catch
            end
        end

        % Value changed function: AllselectedCheckBox
        function AllselectedCheckBoxValueChanged(app, event)
if app.AllselectedCheckBox.Value
app.ClustersCheckBox.Value=false;
app.ClusternonClusterCheckBox.Value=false;
app.ColocalizingCheckBox.Value=false; 
n=app.AllselectedCheckBox.UserData;
%% reset background and recolor in list is not empty
removeStyle(app.Tree);
  if numel(n)>0
%Create your background style
colors = uistyle('BackgroundColor', [1, .87, .13]);%'yellow'
%Apply it
addStyle(app.Tree, colors, 'node', app.SelectCellsToScatterPlotNode.Children(n));
  end
else
removeStyle(app.Tree);
end
app.Tree.SelectedNodes = app.SelectCellsToScatterPlotNode;           
        end

        % Value changed function: ScatterOptions
        function ScatterOptionsValueChanged(app, event)
if ~isempty(app.Tree.CheckedNodes)
app.AllselectedCheckBox.Value=false;
app.ClustersCheckBox.Value=false;
app.ClusternonClusterCheckBox.Value=false;
app.ColocalizingCheckBox.Value=false;

data={app.Tree.CheckedNodes.Text};
data=data(~ismember(data,'Select Cells To Scatter Plot'));
% childNodes = app.SelectCellsToScatterPlotNode.Children;
n=find(ismember({app.SelectCellsToScatterPlotNode.Children.Text},data));

        if numel(n)>0
if app.ScatterOptions.ValueIndex==2
app.AllselectedCheckBox.UserData=n;
    elseif app.ScatterOptions.ValueIndex==3
app.ClustersCheckBox.UserData=n;
        elseif app.ScatterOptions.ValueIndex==4 
            if isscalar(n)
app.ClusternonClusterCheckBox.UserData=n;
            else %ClusternonClusterCheckBox
                 opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} You can only select one cell type for [Cluster & non-cluster option]','ClusToRa plotting error', opts);
return
            end
            elseif app.ScatterOptions.ValueIndex==5
app.ColocalizingCheckBox.UserData=n;    
end
        end
        app.ScatterOptions.ValueIndex=1;   
end
%% selection enable/disable what to plot
if isempty(app.AllselectedCheckBox.UserData)
    app.Label_1.Visible=false; app.AllselectedCheckBox.Enable=false; app.AllselectedCheckBox.Value=false;
else
    app.Label_1.Visible=true; app.AllselectedCheckBox.Enable=true; %app.AllselectedCheckBox.Value=true;
end

if isempty(app.ClustersCheckBox.UserData)
    app.Label_2.Visible=false; app.ClustersCheckBox.Enable=false; app.ClustersCheckBox.Value=false;
else
    app.Label_2.Visible=true; app.ClustersCheckBox.Enable=true; %app.ClustersCheckBox.Value=true;
end

if isempty(app.ClusternonClusterCheckBox.UserData)
    app.Label_3.Visible=false; app.ClusternonClusterCheckBox.Enable=false; app.ClusternonClusterCheckBox.Value=false;
else 
    app.Label_3.Visible=true; app.ClusternonClusterCheckBox.Enable=true; %app.ClusternonClusterCheckBox.Value=true;
end

if isempty(app.ColocalizingCheckBox.UserData) 
    app.Label_4.Visible=false; app.ColocalizingCheckBox.Enable=false; app.ColocalizingCheckBox.Value=false;
else
    app.Label_4.Visible=true; app.ColocalizingCheckBox.Enable=true; %app.ColocalizingCheckBox.Value=true;
end  

%%% enable plotting especially if no levl 2
if strcmp(app.ToplotpreviewDropDown.Value,'Level1') & isscalar(app.ToplotpreviewDropDown.Items)
    if app.Label_1.Visible || app.Label_2.Visible || app.Label_3.Visible || app.Label_4.Visible
    app.PlotScatterButton.Enable=true;
    end
elseif strcmp(app.ToplotpreviewDropDown.Value,'Level2') & isscalar(app.Level2ListBox.ValueIndex)
    if app.Label_1.Visible || app.Label_2.Visible || app.Label_3.Visible || app.Label_4.Visible
    app.PlotScatterButton.Enable=true;
    end
else
    app.PlotScatterButton.Enable=false;
end
        end

        % Value changed function: ClustersCheckBox
        function ClustersCheckBoxValueChanged(app, event)
n=app.ClustersCheckBox.UserData;
if app.ClustersCheckBox.Value
app.AllselectedCheckBox.Value=false;
app.ClusternonClusterCheckBox.Value=false;
% app.ColocalizingCheckBox.Value=false; 

%% reset background if necessary and recolor in list is not empty
if ~app.ColocalizingCheckBox.Value, removeStyle(app.Tree); end
  if numel(n)>0

if numel(n)>1 & app.ColocalizingCheckBox.Value
    % removeStyle(app.Tree);
    app.ClustersCheckBox.Value=false;
 opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Reselect just one in [Cluster(s) option] to plot with co-localizing groups of interest','ClusToRa plotting error', opts);
return
else
    %Create your background style
colors = uistyle('BackgroundColor', [1, .87, .13]);%'yellow'
%Apply it
addStyle(app.Tree, colors, 'node', app.SelectCellsToScatterPlotNode.Children(n));
end

  end
else
% if ~app.ColocalizingCheckBox.Value, removeStyle(app.Tree); end
colors2 = uistyle('BackgroundColor', [1, 1, 1]);
addStyle(app.Tree, colors2, 'node', app.SelectCellsToScatterPlotNode.Children(n));
end
app.Tree.SelectedNodes = app.SelectCellsToScatterPlotNode;
        end

        % Value changed function: ClusternonClusterCheckBox
        function ClusternonClusterCheckBoxValueChanged(app, event)

if app.ClusternonClusterCheckBox.Value
app.AllselectedCheckBox.Value=false;
app.ClustersCheckBox.Value=false;
app.ColocalizingCheckBox.Value=false;
n=app.ClusternonClusterCheckBox.UserData;
%% reset background and recolor in list is not empty
removeStyle(app.Tree);
  if numel(n)>0
%Create your background style
colors = uistyle('BackgroundColor', [1, .87, .13]);%'yellow'
%Apply it
addStyle(app.Tree, colors, 'node', app.SelectCellsToScatterPlotNode.Children(n));
  end
else
removeStyle(app.Tree);
end
app.Tree.SelectedNodes = app.SelectCellsToScatterPlotNode;
        end

        % Value changed function: ColocalizingCheckBox
        function ColocalizingCheckBoxValueChanged(app, event)
n=app.ColocalizingCheckBox.UserData;
if app.ColocalizingCheckBox.Value
app.AllselectedCheckBox.Value=false;
app.ClusternonClusterCheckBox.Value=false;
%% reset background and recolor in list is not empty
if ~app.ClustersCheckBox.Value, removeStyle(app.Tree); end
  if numel(n)>0

%%%% Check if plotting with One clust
if numel(app.ClustersCheckBox.UserData)>1 & app.ClustersCheckBox.Value
    % removeStyle(app.Tree);
    app.ClustersCheckBox.Value=false;
 opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Reselect just one in [Cluster(s) option] to plot with co-localizing groups of interest','ClusToRa plotting error', opts);
return
else
    %Create your background style
colors = uistyle('BackgroundColor', [.65, 1, 1]);%
%Apply it
addStyle(app.Tree, colors, 'node', app.SelectCellsToScatterPlotNode.Children(n));
end
  end
else
% if ~app.ClustersCheckBox.Value, removeStyle(app.Tree); end
colors2 = uistyle('BackgroundColor', [1, 1, 1]);
addStyle(app.Tree, colors2, 'node', app.SelectCellsToScatterPlotNode.Children(n));
end
app.Tree.SelectedNodes = app.SelectCellsToScatterPlotNode;
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
if numel(checkedNodes)>0
    app.ScatterOptions.Enable=true;
else
    app.ScatterOptions.Enable=false;
end
        end

        % Button pushed function: PlotScatterButton
        function PlotScatterButtonPushed(app, event)
% app.AllselectedCheckBox.Value
% app.ClustersCheckBox.Value
% app.ClusternonClusterCheckBox.Value
% app.ColocalizingCheckBox.Value
% ScatterPlot
ClusToRa_gscatterplots(app)
        end

        % Value changed function: delimiterDropDown
        function delimiterDropDownValueChanged(app, event)

            if strcmp(app.delimiterDropDown.Value,'User')
fig = uifigure("Position",[100 200 100 171]);
numField = uieditfield(fig, 'text', 'Position', [10 80 75 20]);
uibutton(fig, 'Text', 'Return Value', ...
    'Position', [10 50 80 20], ...
    'ButtonPushedFcn', @(btn,event) uiresume(fig));
uiwait(fig);
%Check if figure still exists (in case user clicked "X")
if isvalid(fig)
    % NOW grab the number from the field
    inputValue = numField.Value; 
    if ~isempty(inputValue)
        app.delimiterDropDown.UserData = inputValue;
    else
        app.delimiterDropDown.UserData = 'Auto';
    end
    close(fig);
end
            else
                app.delimiterDropDown.UserData = app.delimiterDropDown.Value;
            end
        end

        % Button pushed function: assignbarcodesButton
        function assignbarcodesButtonPushed(app, event)
% hfig = uifigure('Name','Sanity check progress','WindowStyle','modal');
% hfig.Position=[hfig.Position(1:2),hfig.Position(3)*.75,hfig.Position(4)/2];
h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message ='Sanity check';
if app.advancedDropDown.ValueIndex==2
    try
barcodes=table2cell(app.LoadmetadataButton.UserData(:,app.ColumnsListBox.Value));
app.LoadmetadataButton.UserData.Properties.VariableTypes(1)="cell";
app.LoadmetadataButton.UserData(:,app.ColumnsListBox.Value)=cellfun(@(x) cellstr(x{:}),barcodes);
    catch
    end
end

% data=app.LoadmetadataButton.UserData(:,app.ColumnsListBox.Value);
% if strcmp(data.Properties.VariableTypes,'double')
% data=cellfun(@num2str,num2cell(table2array(data)),'UniformOutput',false);
% end
Barcodes=table2cell(app.LoadmetadataButton.UserData(:,app.ColumnsListBox.Value)); %store barcodes for use later in Differential analysis
Barcodes=cellfun(@num2str,Barcodes,'UniformOutput',false);%convert 2 str just incase it's double


if numel(unique(Barcodes))~=size(app.LoadmetadataButton.UserData,1)
    app.barcodescolumn.Value={''};
    close(h)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Barcodes MUST be unique','Assigning error', opts);
return
else
% app.assignbarcodesButton.UserData=table2cell(app.LoadmetadataButton.UserData(:,app.ColumnsListBox.Value));
app.barcodescolumn.Value=app.ColumnsListBox.Value;
close(h)
end
        end

        % Value changing function: searchTextArea
        function searchTextAreaValueChanging(app, event)
try
lia=find(startsWith(app.InteractionforGSEAanalysisListBox.Items',event.Value,'IgnoreCase',true));
app.InteractionforGSEAanalysisListBox.ValueIndex=lia(1); %drawnow; 
scroll(app.InteractionforGSEAanalysisListBox, app.InteractionforGSEAanalysisListBox.Items(lia(1)));
catch
end
        end

        % Value changed function: normalizeCheckBox
        function normalizeCheckBoxValueChanged(app, event)
% if app.normalizeCheckBox.Value
%     Exp=app.DiffSetupPanel.UserData;
%         if app.normalizeCheckBox.Value
% % Exp.dataNorm=((Exp.data./(sum(Exp.data,2))).*median(sum(Exp.data,2))); %sum(data,2);[min(ans) max(ans)] %scanpy.pp.normalize_total
%         end
%     if app.inplaceCheckBox.Value
%         Exp.data=[];
%     end
% end            
        end

        % Button pushed function: ALLinOneButton
        function ALLinOneButtonPushed(app, event)
    %% Reset / disable
% app.inplaceCheckBox.Enable=false;
app.normalizeCheckBox.Enable=false;
app.normalizeCheckBox.Value=false;
app.InteractionforGSEAanalysisListBox.Items={''};
app.DiffSetupPanel.UserData=[];
%% select Expression file and load it
[path,~,~] = fileparts(app.SelectedFilePath.Value);
            [file,location] = uigetfile('*','Select expression file to load in ClusToRa',path{1});
try [~, ~, ext] = fileparts(file);catch, end
% hfig = uifigure('Name','File loading progress','WindowStyle','modal');
% hfig.Position=[hfig.Position(1:2),hfig.Position(3)*.75,hfig.Position(4)/2];

    %%%%file selected sanity test
if isequal(file,0)
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User canceled selection','Reading file error', opts);
    return
elseif ~ismember(ext,{'.txt';'.csv';'.tsv';'.xlsx';'.xls'}) 
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Invalid file type! Supported file types: *.txt, *.csv, *.tsv, *.xlsx, *.xls','Reading file error', opts);
    return
else
h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message =['Please wait while I read and prepare the selected file ( ' file ' )'];

        if strcmp(ext,'.tsv')
            data=readtable(fullfile(location,file),'FileType','text','VariableNamingRule','preserve');
        else
opts = detectImportOptions(fullfile(location,file));
opts.VariableNamesLine=1;
opts.VariableNamingRule='preserve'; 
if isempty(app.delimiterDropDown.UserData) | strcmp(app.delimiterDropDown.UserData,'Auto')
    app.delimiterDropDown.UserData='Auto';
else
    app.delimiterDropDown.UserData
    opts.Delimiter=app.delimiterDropDown.UserData;
end
if app.advancedDropDown.ValueIndex==2
    opts = setvartype(opts, opts.SelectedVariableNames{1}, 'string');
    format long g
end
            data=readtable(fullfile(location,file),opts);
        end
pause(2)
close(h)
%% Sanity Check
ALL=app.SaveButton.UserData;

% confirm barcodes match
lia=ismember(ALL.params.barcodes,table2cell(data(:,1)));
if sum(lia)~=numel(lia)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} All barcodes in ClusToRa metadata analysis must be matched in expression data','Loaded expression data error', opts);
    return
end

try
% update column list
% app.InteractionforGSEAanalysisListBox.Items=data.Properties.VariableNames(2:end);
Exp.barcodes=table2cell(data(:,1));
Exp.features=data.Properties.VariableNames(2:end);
data=sparse(table2array(data(:,2:end)));%save memory
Exp.data=data;
app.DiffSetupPanel.UserData=Exp;
clear data

%% reenable options for normalization
% app.inplaceCheckBox.Enable=true;
app.normalizeCheckBox.Enable=true;

app.PreviewloadButton.Enable=true;
app.PreviewExpButton.Enable=true;
catch ME
     opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg(['\color{red} ' ME.message],'Loaded expression data error', opts);
    return
end
%% Enable the ones reset
% app.PreviewButton.Enable=true;
% app.AssignButton.Enable=true;
% app.ClearButton.Enable=true;
% app.autosaveCheckBox.Enable=true;
end
        end

        % Button pushed function: barcodesButton
        function barcodesButtonPushed(app, event)
    %% Reset / disable
% app.inplaceCheckBox.Enable=false;
app.normalizeCheckBox.Enable=false;
app.normalizeCheckBox.Value=false;
app.InteractionforGSEAanalysisListBox.Items={''};
app.DiffSetupPanel.UserData=[];
            %% load Expressionb barcodes file and load it
            [path,~,~] = fileparts(app.SelectedFilePath.Value);
            [file,location] = uigetfile('*','Select expression barcode file to load in ClusToRa',path{1});
try [~, ~, ext] = fileparts(file);catch, end
% hfig = uifigure('Name','File barcode progress','WindowStyle','modal');
% hfig.Position=[hfig.Position(1:2),hfig.Position(3)*.75,hfig.Position(4)/2];

            if isequal(file,0)
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User canceled selection','Reading file error', opts);
    return
elseif ~ismember(ext,{'.txt';'.csv';'.tsv';'.xlsx';'.xls'}) 
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Invalid file type! Supported file types: *.txt, *.csv, *.tsv, *.xlsx, *.xls','Reading file error', opts);
    return
            else
                h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message =['Please wait while I read and prepare the selected file ( ' file ' )'];
        % Force everything into one column by using a non-existent delimiter
% Import the table
    format long g
    opts = detectImportOptions(fullfile(location,file),'FileType', 'text');
opts = setvartype(opts, 'string');
opts.VariableNamesLine=0;
opts.DataLines=[1,Inf];
    Barcodes=readtable(fullfile(location,file),opts);
Barcodes=cellfun(@char,table2cell(Barcodes(:,1)),'UniformOutput',false);
%% Sanity Check
ALL=app.SaveButton.UserData;

% confirm barcodes match
lia=ismember(ALL.params.barcodes,Barcodes);
if sum(lia)~=numel(lia)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} All barcodes in ClusToRa metadata analysis must be matched in expression data','Loaded expression data error', opts);
    return
end

%%%%%%%%
Exp.barcodes=Barcodes;
app.DiffSetupPanel.UserData=Exp;
app.featuresButton.Enable=true;%enable features
close(h)
            end

        end

        % Button pushed function: featuresButton
        function featuresButtonPushed(app, event)
Exp=app.DiffSetupPanel.UserData;      
%% load Expression features file and load it
[path,~,~] = fileparts(app.SelectedFilePath.Value);
            [file,location] = uigetfile('*','Select expression features file to load in ClusToRa',path{1});
try [~, ~, ext] = fileparts(file);catch, end
% hfig = uifigure('Name','File features progress','WindowStyle','modal');
% hfig.Position=[hfig.Position(1:2),hfig.Position(3)*.75,hfig.Position(4)/2];

            if isequal(file,0)
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User canceled selection','Reading file error', opts);
    return
elseif ~ismember(ext,{'.txt';'.csv';'.tsv';'.xlsx';'.xls'}) 
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Invalid file type! Supported file types: *.txt, *.csv, *.tsv, *.xlsx, *.xls','Reading file error', opts);
    return
            else
                h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message =['Please wait while I read and prepare the selected file ( ' file ' )'];
        % Force everything into one column by using a non-existent delimiter
% Import the table
    format long g
    features=readcell(fullfile(location,file),'FileType','text');
    features=features(:,1)';
Exp.features=features;
app.DiffSetupPanel.UserData=Exp;
app.MTXfileButton.Enable=true;%enable matrix
close(h)
            end
        end

        % Button pushed function: MTXfileButton
        function MTXfileButtonPushed(app, event)
            Exp=app.DiffSetupPanel.UserData;      
%% load Expression data file
[path,~,~] = fileparts(app.SelectedFilePath.Value);
            [file,location] = uigetfile('*','Select expression MTX matrix file to load in ClusToRa',path{1});
try [~, ~, ext] = fileparts(file);catch, end
% hfig = uifigure('Name','File maatrix progress','WindowStyle','modal');
% hfig.Position=[hfig.Position(1:2),hfig.Position(3)*.75,hfig.Position(4)/2];

            if isequal(file,0)
    % close(hfig)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User canceled selection','Reading file error', opts);
    return
            else
                h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message =['Please wait while I read and prepare the selected file ( ' file ' )'];
        % Force everything into one column by using a non-existent delimiter
% Import the table
[data] = mmread(fullfile(location,file));
data=sparse(data);
%% confirm matrix and barcode/features match
if numel(Exp.barcodes)~=size(data,1) & numel(Exp.barcodes)~=size(data,2)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} barcodes size does not match any dimention of the matrix','Reading file error', opts);
    return
end
if numel(Exp.features)~=size(data,1) & numel(Exp.features)~=size(data,2)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} features size does not match any dimention of the matrix','Reading file error', opts);
    return
end

if numel(Exp.barcodes)~=size(data,1)
    data=data';
end

% app.InteractionforGSEAanalysisListBox.Items=Exp.features;
Exp.data=data; clear data
app.DiffSetupPanel.UserData=Exp;

%% reenable options for normalization
% app.inplaceCheckBox.Enable=true;
app.normalizeCheckBox.Enable=true;

app.PreviewloadButton.Enable=true;
app.PreviewExpButton.Enable=true;
close(h)
            end
        end

        % Button pushed function: PreviewloadButton
        function PreviewloadButtonPushed(app, event)
Exp=app.DiffSetupPanel.UserData;      

idata=cell2table([Exp.barcodes(1:100),num2cell(full(Exp.data(1:100,1:100)))],...
    "VariableNames",[{'Barcodes'},Exp.features(1:100)]);

fig = uifigure;
            gl = uigridlayout(fig, [1 1]); % 1 row, 1 column;
uitable(gl, 'Data', idata);%, 'Position', [20 20 500 300]

        end

        % Button pushed function: PreviewExpButton
        function PreviewExpButtonPushed(app, event)
Exp=app.DiffSetupPanel.UserData;      
idx=randi([1, numel(Exp.barcodes)], 50, 1);%randomly pick 50 cells;
if app.normalizeCheckBox.Value
Exp.data=((Exp.data./(sum(Exp.data,2))).*median(sum(Exp.data,2))); %sum(data,2);[min(ans) max(ans)] %scanpy.pp.normalize_total
end
idata=full(Exp.data(idx,:))';
colSum = cellfun(@num2str,num2cell(sum(idata, 1)),'UniformOutput',false);
fig=uifigure; haxes=axes(fig); boxplot(haxes,log1p(idata))
xticklabels(haxes,colSum)
if isscalar(unique(colSum))
    title(haxes,'Data is normalized')
elseif (max(unique(sum(idata)))/min(unique(sum(idata))))<1.5
    title(haxes,'Data might be normalized')
else
    title(haxes,'Looks like your data migt not be normalized, enable normalize option')
end
        end

        % Button pushed function: RunDifferentialButton
        function RunDifferentialButtonPushed(app, event)
% reset
app.GeneAnalysisButton.Enable=false;
app.RunDifferentialButton.Enable=false;
app.ExportButton.Enable=false;
app.PreviewDiffButton.Enable=false;

% hfig = uifigure('Name','Differential analysis'); hfig.Position(4)= hfig.Position(4)*.6;%,'WindowStyle','modal'
% try
% h=uiprogressdlg(hfig,'Icon','coffee.gif','Title','Progress...','Indeterminate','on');
% catch
% h=uiprogressdlg(hfig,'Title','Samples analysis progress...','Indeterminate','on');
% end

%% Get what we need for this analysis
ALL=app.SaveButton.UserData;% ALL of ClusToRa results
Exp=app.DiffSetupPanel.UserData; % Expression data loaded
if isempty(Exp)
    app.RunDifferentialButton.Enable=true;
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} You need to load your expression data first!!','Expression file error', opts);
    return
end
%% Normalize data if indicated and sanity check
if app.normalizeCheckBox.Value
Exp.data=((Exp.data./(sum(Exp.data,2))).*median(sum(Exp.data,2))); %sum(data,2);[min(ans) max(ans)] %scanpy.pp.normalize_total
end
% test if data is normalized
idx=randi([1, numel(Exp.barcodes)], 50, 1);%randomly pick 50 cells;
idata=full(Exp.data(idx,:))';
colSum = cellfun(@num2str,num2cell(sum(idata, 1)),'UniformOutput',false);
        if isscalar(unique(colSum))
        elseif (max(unique(sum(idata)))/min(unique(sum(idata))))<1.5
selection = uiconfirm(app.UIFigure, ...
    'Data might be normalized. Should the process continue?', 'Execution Decision', ...
    'Options', {'Ignore and continue', 'Stop ClusToRa Diff'}, ...
    'DefaultOption', 1, 'CancelOption', 2);
% Handle the response
if strcmp(selection, 'Stop ClusToRa Diff')
    return; % Stops the current execution
end
        else
    selection = uiconfirm(app.UIFigure, ...
    'Looks like your data is might not be normalized, enable normalize option (see ClusToRa manual) or continue if you independently normalized it. Should the process continue?', 'Execution Decision', ...
    'Options', {'Ignore and continue', 'Stop ClusToRa Diff'}, ...
    'DefaultOption', 1, 'CancelOption', 2);
% Handle the response
if strcmp(selection, 'Stop ClusToRa Diff')
    return; % Stops the current execution
end
        end
%% Run ClusTora Diff
ClusToRaDIFF={};
nLevels=size(ALL.Diff,2);
iSamples=size(ALL.Diff,1);
Thresh=app.ZthreshSpinner.Value;

h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message ='ClusToRa Differential Analysis in progress...see gauge for progress';

for i=1:iSamples
    app.DiffrunprogressGauge.Value=(i/(iSamples+1))*100;
  % h.Message =['ClusToRa Differential on ' ALL.Diff{i,1} ':- ' ALL.Diff{i,2} ', (' num2str(i) '/' num2str(iSamples) ')'] ; 
  if nLevels==2
Results=ALL.Results.(ALL.Diff{i,1}).(ALL.Diff{i,2}).Results;
params=ALL.Results.(ALL.Diff{i,1}).(ALL.Diff{i,2}).params;
  elseif nLevels==1
Results=ALL.Results.(ALL.Diff{i,1}).Results;
params=ALL.Results.(ALL.Diff{i,1}).params;
  end
  params.barcodes=ALL.params.barcodes; %global barcodes
[DEGs,colocGroups]=ClusToRa_ColocDiff(Results,params,Exp,Thresh);

ClusToRaDIFF.(ALL.Diff{i,1}).(ALL.Diff{i,2}).DEGs=DEGs;
ClusToRaDIFF.(ALL.Diff{i,1}).(ALL.Diff{i,2}).colocGroups=colocGroups;
end

ClusToRaDIFF.nLevels=nLevels;
app.RunDifferentialButton.UserData=ClusToRaDIFF;

%reset
app.GeneAnalysisButton.Enable=true;
app.ExportButton.Enable=true;
app.RunDifferentialButton.Enable=true;
app.PreviewDiffButton.Enable=true;
app.DiffrunprogressGauge.Value=100;
close(h)
%% Save results
if app.autosaveCheckBox.Value
SaveButtonPushed(app, event)
else
app.SaveButton.Enable=true;
end

Level1ListBoxValueChanged(app, event)
        end

        % Button pushed function: AdddatabaseButton
        function AdddatabaseButtonPushed(app, event)
fig = uifigure("Position",[200 400 500 120]);
txtField = uieditfield(fig, 'text', 'Position', [10 80 450 30]);
uibutton(fig, 'Text', 'Add Database', ...
    'Position', [10 50 150 20], 'BackgroundColor','y',...
    'ButtonPushedFcn', @(btn,event) uiresume(fig));
uiwait(fig);
%Check if figure still exists (in case user clicked "X")
if isvalid(fig)
    % NOW grab the text from the field
    inputValue = txtField.Value; 
    if ~isempty(inputValue)
        uitreenode(app.SelectGeneSetdatabaseNode, 'Text', inputValue);       
expand(app.SelectGeneSetdatabaseNode);    
    end
    close(fig);
end
            
   
        end

        % Value changed function: Level2ListBox
        function Level2ListBoxValueChanged(app, event)
if isscalar(app.Level2ListBox.ValueIndex) | strcmp(app.ToplotpreviewDropDown.Value,'Level1')
    app.GeneAnalysisButton.Enable=true;
    app.PreviewDiffButton.Enable=true;
else
    app.GeneAnalysisButton.Enable=false;
    app.PreviewDiffButton.Enable=false;
end
            Level1ListBoxValueChanged(app, event); % update Diff if available
            
        end

        % Button pushed function: PreviewDiffButton
        function PreviewDiffButtonPushed(app, event)

ClusToRaDIFF=app.RunDifferentialButton.UserData; %Get Diff results
value = app.Level1ListBox.ItemsData{app.Level1ListBox.ValueIndex};
    if ClusToRaDIFF.nLevels==1
ForDEGs=ClusToRaDIFF.(value).DEGs;
filename=value;
grouped=false;
    elseif ClusToRaDIFF.nLevels==2 & strcmp(app.ToplotpreviewDropDown.Value,'Level2')
        if ~isscalar(app.Level2ListBox.ValueIndex)
opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Select just one sample in level 2 for PreviewDiff, otherwise select group (level1) for integration!','GDEA error', opts);
    return
        end
ForDEGs=ClusToRaDIFF.(value).(app.Level2ListBox.ItemsData{app.Level2ListBox.ValueIndex}).DEGs;
filename=[value '-' app.Level2ListBox.ItemsData{app.Level2ListBox.ValueIndex}];
grouped=false;
    elseif ClusToRaDIFF.nLevels==2 & strcmp(app.ToplotpreviewDropDown.Value,'Level1') %combined
filename=value;
grouped=true;
        %% Pval inegration for groups
GSEAgroups={};
        for i=1:numel(app.Level2ListBox.Items)
GSEAgroups=[ClusToRaDIFF.(value).(app.Level2ListBox.ItemsData{i}).colocGroups;GSEAgroups];
        end
GSEAgroups=unique(GSEAgroups);
Genes=ClusToRaDIFF.(value).(app.Level2ListBox.ItemsData{1}).DEGs(2:end,1);
ForDEGs=[];% to store integrated 
TableHead={'Features'};
Thresh = app.minsampleSpinner.Value/100;%% of the samples show same sign of change for a given interaction;

for i=1:numel(GSEAgroups)
    P_matrix   =nan(numel(Genes),numel(app.Level2ListBox.Items));
    LFC_matrix =nan(numel(Genes),numel(app.Level2ListBox.Items));
    match = regexp(GSEAgroups{i}, '.*?\[Coloc', 'match');

    iN=zeros(numel(app.Level2ListBox.Items),1);%numerate for minSamples representation
    for j=1:numel(app.Level2ListBox.Items)
lia =find(startsWith(ClusToRaDIFF.(value).(app.Level2ListBox.ItemsData{j}).DEGs(1,:), match{1}));  
if ~isempty(lia)
    iN(j)=1;
    LFC_matrix(:,j)=cell2mat(ClusToRaDIFF.(value).(app.Level2ListBox.ItemsData{j}).DEGs(2:end,lia(3)));
    P_matrix(:,j)=cell2mat(ClusToRaDIFF.(value).(app.Level2ListBox.ItemsData{j}).DEGs(2:end,lia(4)));
end
    end
    %integration
    iN=logical(iN);
    if sum(iN)/numel(iN) >= app.minsampleSpinner.Value/100 %minimum representation
        [combined_p,avg_LFC,is_consistent]=ClusToRa_Diff_Integration(P_matrix(:,iN),LFC_matrix(:,iN),Thresh);
        combined_p(~is_consistent)=nan;
        avg_LFC(~is_consistent)=nan;
        FDR=mafdr(combined_p);
TableHead=[TableHead,{[GSEAgroups{i} ' Log2FC']},{[GSEAgroups{i} ' Fisher_p_value']},{[GSEAgroups{i} ' FDR']}];
ForDEGs=[ForDEGs,[avg_LFC,combined_p,FDR]];
    end

end
ForDEGs=[TableHead;[Genes,num2cell(ForDEGs)]];

%%
    end
DEGs.ForDEGs=ForDEGs;
DEGs.filename=filename;
DEGs.grouped=grouped;
app.PreviewDiffButton.UserData=DEGs;

    if app.ShowDiffPreview.Value
% fig = uifigure;
% uitable(fig,'Data',ForDEGs,'ColumnName',{},'RowName',[],'Units','normalized','Position', [0 0 1 1]);         % no row headers    
ClusToRa_showTable(cell2table(ForDEGs,'VariableNames',cellfun(@num2str,num2cell(1:size(ForDEGs,2)),'UniformOutput',false)),...
    [app.Diff_selected.Value{1} '_ClusToRaDifferential'])

    end
        end

        % Button pushed function: GeneAnalysisButton
        function GeneAnalysisButtonPushed(app, event)
if isempty(app.Tree_2.CheckedNodes)
opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} No GSEA database selected!','GDEA error', opts);
    return
else
genesets={app.Tree_2.CheckedNodes.Text};
genesets=genesets(~ismember(genesets,'Select GeneSet database'));
genesets=cellfun(@string,genesets);

Pvalcut=app.EnrichralphaSpinner.Value;
minumgene=app.minGenesSpinner.Value;
end           
            
iGroup=app.InteractionforGSEAanalysisListBox.Value; %Group for GSEA
match = regexp(iGroup, '.*?\[Coloc', 'match');

% get FoDEGs
app.ShowDiffPreview.Value=false;
app.PreviewDiffButton.Enable=false;

PreviewDiffButtonPushed(app, event)% Compute DEGs 
DEGs=app.PreviewDiffButton.UserData;

filename=DEGs.filename;
grouped=DEGs.grouped;
DEGs=DEGs.ForDEGs;

lia =find(startsWith(DEGs(1,:), match{1}));  

if grouped
DEGs=DEGs(:,[1,lia]);
else
DEGs=DEGs(:,[1,lia(3:end)]);
end

    %% GSEA 
Genes=DEGs(2:end,1);
data=cell2mat(DEGs(2:end,2:end));

if app.DiffType.ValueIndex==1
    pVal=data(:,2); % pval
else
    pVal=data(:,3); %FDR
end
UpGenes=Genes(pVal<app.DiffalphaSpinner.Value & data(:,1)>0);
DownGenes=Genes(pVal<app.DiffalphaSpinner.Value & data(:,1)<0);

%% Enrichr
backgroundlist=cellfun(@string,Genes);

%% Paths down
        if ~isempty(DownGenes)
genelist = cellfun(@string,DownGenes);
output = ml_Enrichr(genelist, backgroundlist, genesets, minumgene, Pvalcut);
output = EnrichrTable(output);%Enrichr API
try
output = output(:,["GeneSetLibrary","TermName","P_value","AdjustedP_value","OddsRatio","CombinedScore","OverlappingGenes"]);
catch
end
ClusToRa_showTable(output,[filename '-' match{1} 'Downregulated'])
        end
%% Path Up
         if ~isempty(UpGenes)
genelist = cellfun(@string,UpGenes);
output = ml_Enrichr(genelist, backgroundlist, genesets, minumgene, Pvalcut);
output = EnrichrTable(output);%Enrichr API
try
output = output(:,["GeneSetLibrary","TermName","P_value","AdjustedP_value","OddsRatio","CombinedScore","OverlappingGenes"]);
catch
end
ClusToRa_showTable(output,[filename '-' match{1} 'Upregulated'])
        end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
h.Message ='Saving your ClusToRa Differential Results per sample';
app.ShowDiffPreview.Value=false;
app.PreviewDiffButton.Enable=false;

ClusToRaDIFF=app.RunDifferentialButton.UserData;
        [path,  ~, ~] = fileparts(app.SelectedFilePath.Value);
        % VarName=VarName{1};
folderPath = uigetdir(path{1},'select folder to save Diff results'); 
% VarName=['ClusToRa_DIFF_' VarName '_'];

filenames=app.SaveButton.UserData.Diff;
for i=1:size(filenames,1)
    if size(filenames,2)==2
DEGs=ClusToRaDIFF.(filenames{i,1}).(filenames{i,2}).DEGs;
filename=[filenames{i,1} '-' filenames{i,2}];
    else
DEGs=ClusToRaDIFF.(filenames{i}).DEGs;
filename=filenames{i,1};
    end
        if app.typeDropDown.ValueIndex==1 %tab delimited
writecell(DEGs,[folderPath filesep 'ClusToRa_DIFF_' filename '.txt'],'Delimiter', 'tab')
        elseif app.typeDropDown.ValueIndex==2 %excel
writecell(DEGs,[folderPath filesep 'ClusToRa_DIFF.xlsx'],'Sheet',filename)
        end
end
%% save integrated if two levels

% DEGs.ForDEGs=ForDEGs;
% DEGs.filename=filename;

if ClusToRaDIFF.nLevels==2 
    h.Message ='Saving your ClusToRa Differential Results per group [Fishers P-value Integration]';
        app.ToplotpreviewDropDown.Value='Level1'; %set combined
    for i=1:numel(app.Level1ListBox.Items)
app.Level1ListBox.ValueIndex=i; %loop through groups
Level1ListBoxValueChanged(app, event);
            for j=1:numel(app.Level2ListBox.Items)
                app.Level2ListBox.ValueIndex=j; %loop through groups samples
PreviewDiffButtonPushed(app, event)% pval integradtion
DEGs=app.PreviewDiffButton.UserData;
            end
        if app.typeDropDown.ValueIndex==1 %tab delimited
writecell(DEGs.ForDEGs,[folderPath filesep 'Combined_ClusToRa_DIFF_' DEGs.filename '.txt'],'Delimiter', 'tab')
        elseif app.typeDropDown.ValueIndex==2 %excel
writecell(DEGs.ForDEGs,[folderPath filesep 'Combined_ClusToRa_DIFF.xlsx'],'Sheet',DEGs.filename)
        end
    end
end
close(h)
        end

        % Button pushed function: ColocMatrixButton
        function ColocMatrixButtonPushed(app, event)
%get filenames to save
h=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
  [path,  ~, ~] = fileparts(app.SelectedFilePath.Value);
folderPath = uigetdir(path{1},'select folder to save ColocMatrix'); 
%setup

Thresh=app.ThreshSpinner.Value;
filenames={};
if strcmp(app.ToplotpreviewDropDown.Value,'Level2') & numel(app.ToplotpreviewDropDown.Items)==2
     n=app.Level2ListBox.ValueIndex;
     for i=1:numel(n)
   filenames{i,1}=app.Level1ListBox.ItemsData{app.Level1ListBox.ValueIndex};
   filenames{i,2}=app.Level2ListBox.ItemsData{n(i)};
     end
else
filenames=app.Level1ListBox.ItemsData{app.Level1ListBox.ValueIndex};
end

ALL=app.SaveButton.UserData;
ColocMat=struct;
                for i=1:size(filenames,1)
                    h.Message ='Computing ColocMatrix';
    if size(filenames,2)==2
Results=ALL.Results.(filenames{i,1}).(filenames{i,2}).Results;
params=ALL.Results.(filenames{i,1}).(filenames{i,2}).params;
    else
Results=ALL.Results.(filenames{i}).Results;
params=ALL.Results.(filenames{i}).params;
    end

%% Get coloc Matrix
ColocMat.([filenames{i,:}])=ClusToRa_ColocMatrix(Results,params,Thresh);
                end
                %% save files
            h.Message ='Saving your ColocMatrix (can take long)';
        if strcmp(app.ColocMatrixFileType.Value,'matlab') 
save([folderPath filesep 'ColocMat.mat'],'ColocMat')
        elseif strcmp(app.ColocMatrixFileType.Value,'mtx') 
for i=1:size(filenames,1)
    data=ColocMat.([filenames{i,:}]);
    mmwrite([folderPath filesep 'ColocMat_' [filenames{i,:}] '_matrix.mxt'],sparse(cell2mat(data(2:end,4:end))))
    writecell(data(2:end,1:3),[folderPath filesep 'ColocMat_' [filenames{i,:}] '_barcodesXY.txt'],'Delimiter', 'tab')
    writecell(data(1,4:end)',[folderPath filesep 'ColocMat_' [filenames{i,:}] '_features.txt'],'Delimiter', 'tab')
end
        elseif strcmp(app.ColocMatrixFileType.Value,'txt') 
for i=1:size(filenames,1)
    writecell(ColocMat.([filenames{i,:}]),[folderPath filesep 'ColocMat_' [filenames{i,:}] '.txt'],'Delimiter', 'tab')
end
        end
close(h)
        end

        % Value changed function: xygCheckBox
        function xygCheckBoxValueChanged(app, event)
if app.xygCheckBox.Value && app.StartClusToRaButton.Enable
    app.metaCheckBox.Value=false;
elseif ~isempty(app.SaveButton.UserData) & ~isempty(app.SaveButton.UserData.image)
    app.metaCheckBox.Value=false;
    app.PreviewButton.Enable=true;
else
    app.xygCheckBox.Value=false;
end
        end

        % Value changed function: metaCheckBox
        function metaCheckBoxValueChanged(app, event)
if app.metaCheckBox.Value
  app.xygCheckBox.Value=false;  
end
            
        end

        % Button pushed function: SavePlotsButton
        function SavePlotsButtonPushed(app, event)
ext=app.filetypetosave.Value;
dpi=app.DPItosave.Value;
ContentType='vector';

h =  findobj('type','figure');
n = length(h);
hh=uiprogressdlg(app.UIFigure,'Icon','coffee.gif','Title','Progress','Indeterminate','on');
hh.Message =['Saving your files to ' ext ' format'];
folderPath = uigetdir(pwd,'Select folder where plots will be saved'); 
for i=1:n
    fig=h(i);
filename=fig.Name;%simplify to figure name
if isempty(filename); filename=['Fig' num2str(fig.Number)]; end

    if ~isempty(filename)
        filename= [filename '.' ext]; 
j=0;
filenameNumerator=filename;
while isfile(filename)
            j=j+1;
        filename=[filenameNumerator(1:end-4) '(' num2str(j) ').' ext];
% else
end
if j~=0
filename=[filenameNumerator(1:end-4) '(' num2str(j) ').' ext];
end
 disp(filename)
 hh.Message =['Saving ' filename '(' num2str(i) '/' num2str(n) ')'];
% end'vector'
try 
exportgraphics(fig,[folderPath filesep filename],'ContentType',ContentType,"Resolution",dpi)
catch
    filename=['Fig' num2str(fig.Number)];filename= [filename '.' ext]; disp(filename)
    exportgraphics(fig,[folderPath filesep filename],'ContentType',ContentType,"Resolution",dpi)
end
    end
end
disp(['done saving all figures in ' ext ' format!'])
close(hh)
        end

        % Value changed function: CircleFill
        function CircleFillValueChanged(app, event)
if app.CircleFill.ValueIndex==1 
    app.CircleSize.ValueIndex=1;
else
    app.CircleSize.ValueIndex=2;
end      
        end

        % Value changed function: CircleSize
        function CircleSizeValueChanged(app, event)
if app.CircleSize.ValueIndex==1 
    app.CircleFill.ValueIndex=1;
else
    app.CircleFill.ValueIndex=2;
end   
        end

        % Button pushed function: ClustersbarssummaryButton
        function ClustersbarssummaryButtonPushed(app, event)
ALL=app.SaveButton.UserData;  

nLevels=size(ALL.Diff,2);
iSamples=1:size(ALL.Diff,1); %%iSamples=[1:9,17:25,10:16,26:27];%

if ~isempty(ALL.CellTypes2)
    CellTypes=ALL.CellTypes2(:,1);
    CellTypes2=ALL.CellTypes2(:,2);
else
    CellTypes=ALL.CellTypes;
    CellTypes2=[];
end
%%%%% MAY 2026
RGB=ALL.RGB;%
if app.colorsDropDown.ValueIndex==1 || app.colorsDropDown.ValueIndex==2
%%default colors
[~,locb]=ismember(CellTypes,RGB(:,1));
colors=cell2mat(RGB(locb,2:4));

else
data=app.colorsDropDown.UserData; % col1 & 2 text, 3:5 rgb 
%sort cells by
    [lia,locb]=ismember(data(:,1),CellTypes);
    if sum(~lia)==0 % make sure all matched, if not, omit
    CellTypes=CellTypes(locb);
        ALL.CellTypes=CellTypes;%update for chord
            if ~isempty(CellTypes2)
                CellTypes2=CellTypes2(locb);
                    ALL.CellTypes2=[CellTypes,CellTypes2];
            end
    colors=cell2mat(data(:,2:4));
    ALL.RGB=[CellTypes,num2cell(colors)];
    app.SaveButton.UserData=ALL;  % update to new order / colors
    else
        colors=cell2mat(RGB(:,2:4));
    end

end
%%%%%%%%%%%%%%%
%% Plot clusters summary in Clarks Evan Index was computed
if ALL.params.Clumpiness
ClustSummary={};% Col1 PercentInCluster and Clo2 CEI (clumpiness) if available

for i=iSamples
  if nLevels==2
iClustPerc=ALL.Results.(ALL.Diff{i,1}).(ALL.Diff{i,2}).Results.PercentClustering;
iCEI=ALL.Results.(ALL.Diff{i,1}).(ALL.Diff{i,2}).Results.ClarkEvansIndex;
  elseif nLevels==1
iClustPerc=ALL.Results.(ALL.Diff{i,1}).Results.PercentClustering;
iCEI=ALL.Results.(ALL.Diff{i,1}).Results.ClarkEvansIndex;
  end
[lia,locb]=ismember(CellTypes,iClustPerc(:,1)); locb(locb==0)=[];
ClustSummary(lia,end+1:end+2)=[iClustPerc(locb,2),iCEI(locb)];
end
ClustSummary(~(cell2mat(cellfun(@isnumeric,ClustSummary,'UniformOutput',false))))={nan};
ClustSummary(cell2mat(cellfun(@isempty,ClustSummary,'UniformOutput',false)))={nan};
ClustSummary=cell2mat(ClustSummary);

ClustPerc=ClustSummary(:,1:2:end);
CEI=ClustSummary(:,2:2:end); %O_range[1 13]

if app.CircleFill.ValueIndex==1
    O_fill=ClustPerc; lgdTitle1='% forming clusters';%colorbar
elseif app.CircleFill.ValueIndex==2
    O_fill=CEI; lgdTitle1='Clark Evans Index';%colorbar
end

if app.CircleSize.ValueIndex==1
    O_range=[12 13];lgdTitle2=[];%circle legend
elseif app.CircleSize.ValueIndex==2
    O_range=[1 13]; lgdTitle2='% forming clusters';
end
O_size=ClustPerc;
%% plotting
% Sample Data Setup
Samples = ALL.Diff(iSamples,:); if nLevels==2, Samples=join(Samples,'-');end
Samples = strrep(Samples,'_','-');

ClusToRa_showTable(cell2table([CellTypes,num2cell(CEI)],"VariableNames",...
    [{'CellTypes'};join([repmat({'CEI'},numel(Samples),1) Samples],':-')]),...
    'Clark–Evans index')
ClusToRa_showTable(cell2table([CellTypes,num2cell(ClustPerc)],"VariableNames",...
    [{'CellTypes'};join([repmat({'Percent forming clusters'},numel(Samples),1) Samples],':-')]),...
    'Percent forming clusters')

% Create coordinates for the grid
[C, R] = meshgrid(1:length(Samples), 1:length(CellTypes));
figure;
% Plotting
% Use (:) to flatten matrices into vectors
bubblechart(C(:), R(:), O_size(:), O_fill(:),'MarkerFaceAlpha',1,'MarkerEdgeColor','k');
bubblesize(O_range)
% Formatting
set(gca, 'YTick', 1:length(CellTypes), 'YTickLabel', CellTypes);
set(gca, 'XTick', 1:length(Samples), 'XTickLabel', Samples);
set(gca,'YDir','reverse');
grid on; X=xlim;Y=ylim; xlim([X(1)-.5,X(2)+.5]), ylim([Y(1)-.5,Y(2)+.5])
clr_lgd=colorbar; % Shows scale for Mean Expression
clr_lgd.Label.String =lgdTitle1;
% clr_lgd.Label.Position(1) = -2.5;
if ~isempty(lgdTitle2)
    bblg=bubblelegend(lgdTitle2); % Shows scale for Circle Size
bblg.Location="southeastoutside";
end

% eval(['colormap(' app.ColormapDropDown.Value '(101));'])
%Pauls idea (R~1==midrange)
if strcmp(app.ColormapDropDown.Value,'ClusToRa_colormap')
[~,idx]=min(abs(linspace(min(O_fill(:)), max(O_fill(:)), 101)-1));%index of closest to 1
cmap1=ClusToRa_colormap((idx-1)*4+1); cmap1=cmap1(1:(idx-1)*2+1,:);
cmap2=ClusToRa_colormap(((101-idx))*4+1); cmap2=cmap2((101-idx)*2+2:end,:);
colormap([cmap1;cmap2])
else
eval(['colormap(' app.ColormapDropDown.Value '(101));'])
end


hfig=gcf; hfig.Name='Clusters_Summary';
else
     opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Skipping cluster summary as CEI (clumpiness) was not computed during ClusToRa Analysis!','CEI not found error', opts);

end

%% Plot stacked bars

CellTypesSummary={};
Samples = ALL.Diff(iSamples,:); if nLevels==2, Samples=join(Samples,'-');end
Samples = strrep(Samples,'_','-');
for i=iSamples
  if nLevels==2
iPerc=tabulate(ALL.Results.(ALL.Diff{i,1}).(ALL.Diff{i,2}).params.Groups);
  elseif nLevels==1
iPerc=ALL.Results.(ALL.Diff{i,1}).params.Groups;
  end
[lia,locb]=ismember(CellTypes,iPerc(:,1)); locb(locb==0)=[];
CellTypesSummary(lia,end+1)=iPerc(locb,end);
end
% CellTypesSummary(~(cell2mat(cellfun(@isnumeric,CellTypesSummary,'UniformOutput',false))))={0};
CellTypesSummary(cell2mat(cellfun(@isempty,CellTypesSummary,'UniformOutput',false)))={0};
CellTypesSummary=cell2mat(CellTypesSummary);

ClusToRa_showTable(cell2table([CellTypes,num2cell(CellTypesSummary)],"VariableNames",...
    [{'CellTypes'};Samples]),'Relative percent of cell types')
%% Plot cell types grouping if available
        if ~isempty(CellTypes2) %
data2=[];
CellsIDs2=unique(CellTypes2,'stable');
for i=1:numel(CellsIDs2)
    data2=[data2;sum(CellTypesSummary(ismember(CellTypes2,CellsIDs2(i)),:))];
end
hfig=figure;%haxes=axes; hfig.Position=[1     1   695   816]; 
hfig.Name='CellTypesGroupsBarPlots';
bh=bar(flipud(data2)','stacked','FaceColor','flat'); box off%flipud()
set(gca,'XTick',1:size(data2,2), 'XTickLabel',Samples)
title('Cell types per sample'); ylabel('Relative abundance (%)')
legend(flipud(CellsIDs2),'FontSize',6,'Location','eastoutside')% flipud()
% colors2 = mat2cell(flipud(distinguishable_colors(numel(bh))),ones(numel(bh),1), 3); 
func = @(x) colorspace('RGB->Lab',x);
colors2 = distinguishable_colors(numel(bh),'w',func); 
colors2 = mat2cell(colors2,ones(numel(bh),1), 3); 
set(bh, {'CData'}, colors2) % using jet colormap
ylim([0,100])
        end
%% Plot cell types
hfig=figure;%haxes=axes; hfig.Position=[1     1   695   816]; 
hfig.Name='CellTypesBarPlots';
bh=bar(flipud(CellTypesSummary)','stacked','FaceColor','flat'); box off%flipud()
set(gca,'XTick',1:size(CellTypesSummary,2), 'XTickLabel',Samples)
title('Cell types per sample'); ylabel('Relative abundance (%)')
legend(flipud(CellTypes),'FontSize',6,'Location','eastoutside')% flipud()

% colors = mat2cell(cell2mat(flipud(RGB(:,2:4))),ones(numel(bh),1), 3); 
colors = mat2cell(flipud(colors),ones(numel(bh),1), 3); 

set(bh, {'CData'}, colors) % using jet colormap
ylim([0,100])

% data = cell2mat(data) ./ sum(cell2mat(data), 1) * 100;sum(data)
% %Tier 1
% data2=[];
% CellsIDs2=unique(CellsIDs(:,1),'stable')
% for i=1:numel(CellsIDs2)
%     data2=[data2;sum(data(ismember(CellsIDs(:,1),CellsIDs2(i)),:))];
% end
        end

        % Value changed function: ShowDiffPreview
        function ShowDiffPreviewValueChanged(app, event)
if app.ShowDiffPreview.Value
app.PreviewDiffButton.Enable=true;
else
app.PreviewDiffButton.Enable=false;
end
        end

        % Value changed function: colorsDropDown
        function colorsDropDownValueChanged(app, event)
if app.colorsDropDown.ValueIndex==2 %export RGB and Celltypes
data=app.SaveButton.UserData.RGB;  
% [path,  ~, ~] = fileparts(app.SelectedFilePath.Value);
    path = uigetdir(pwd,'Select folder where RGB file will be saved'); 
    if isequal(path, 0)
        opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User cancelled folder selection!','Folder selection error', opts);
return
    end
writecell(data,...
[path filesep 'ClusToRa_RGB_' char(datetime('now','Format','uuuu-MM-dd_HH_mm')) '.txt'],'Delimiter', 'tab');
app.colorsDropDown.ValueIndex=1;
elseif app.colorsDropDown.ValueIndex==3 %import

[file,location] = uigetfile('*.txt','Select text file with RGB');
    %%%%file selected sanity test
if isequal(file,0)
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} User canceled selection','Reading file error', opts);
    return
else
data=readcell(fullfile(location,file));   
    if size(data,2)<4
    opts = struct('WindowStyle','modal','Interpreter','tex');
warndlg('\color{red} Invalid file size! Import RGB file must have 4 or more columns','Invalid file error', opts);
    return
    end
app.colorsDropDown.UserData=data;
end

end            
        end

        % Value changed function: cMaxChord
        function cMaxChordValueChanged(app, event)
if app.cMaxChord.ValueIndex==4
fig = uifigure("Position",[100 200 100 171]);
numField = uieditfield(fig, 'numeric', 'Position', [10 80 75 20]);
uibutton(fig, 'Text', 'cMax Value', ...
    'Position', [10 50 80 20], ...
    'ButtonPushedFcn', @(btn,event) uiresume(fig));
uiwait(fig);
%Check if figure still exists (in case user clicked "X")
if isvalid(fig)
    % NOW grab the number from the field
    inputValue = numField.Value; 
    if inputValue <= 0
        app.cMaxChord.UserData = [];
        app.cMaxChord.ValueIndex = 2;
    else
        app.cMaxChord.UserData = inputValue;
    end
    close(fig);
end
            else
                app.cMaxChord.UserData = [];
                app.cMaxChord.ValueIndex = 2;
end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClusToRaAnalysisTab = uitab(app.TabGroup);
            app.ClusToRaAnalysisTab.Title = 'ClusToRa Analysis';
            app.LoadmetadataButton = uibutton(app.ClusToRaAnalysisTab, 'push');
            app.LoadmetadataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmetadataButtonPushed, true);
            app.LoadmetadataButton.Tooltip = {'button used to load your metadata file (cells as rows, variables as columns). Supported formats: .txt, .csv, .tsv, .xlsx, and .xls'};
            app.LoadmetadataButton.Text = 'Metadata';
            app.SelectedFilePath = uitextarea(app.ClusToRaAnalysisTab);
            app.SelectedFilePath.Editable = 'off';
            app.PreviewButton = uibutton(app.ClusToRaAnalysisTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Enable = 'off';
            app.PreviewButton.Tooltip = {'button used to generate and display the selected preview'};
            app.PreviewButton.Text = 'Preview';
            app.AssignButton = uibutton(app.ClusToRaAnalysisTab, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Enable = 'off';
            app.AssignButton.Tooltip = {'button that assigns the selected column to the checked field(s) '};
            app.AssignButton.Text = 'Assign >';
            app.XcoordCheckBox = uicheckbox(app.ClusToRaAnalysisTab);
            app.XcoordCheckBox.ValueChangedFcn = createCallbackFcn(app, @XcoordCheckBoxValueChanged, true);
            app.XcoordCheckBox.Tooltip = {'checkbox indicating that the selected column should be assigned as the X coordinates (required)'};
            app.XcoordCheckBox.Text = 'X coord';
            app.XcoordCheckBox.Value = true;
            app.YcoordCheckBox = uicheckbox(app.ClusToRaAnalysisTab);
            app.YcoordCheckBox.ValueChangedFcn = createCallbackFcn(app, @YcoordCheckBoxValueChanged, true);
            app.YcoordCheckBox.Tooltip = {'checkbox indicating that the selected column should be assigned as the Y coordinates (required)'};
            app.YcoordCheckBox.Text = 'Y coord';
            app.CelltypeLevel1CheckBox = uicheckbox(app.ClusToRaAnalysisTab);
            app.CelltypeLevel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @CelltypeLevel1CheckBoxValueChanged, true);
            app.CelltypeLevel1CheckBox.Tooltip = {'checkbox indicating that the selected column should be assigned as the X coordinates (required)'};
            app.CelltypeLevel1CheckBox.Text = 'Cell type (Level 1)';
            app.CelltypeLevel2CheckBox = uicheckbox(app.ClusToRaAnalysisTab);
            app.CelltypeLevel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CelltypeLevel2CheckBoxValueChanged, true);
            app.CelltypeLevel2CheckBox.Tooltip = {'checkbox indicating that the selected column should be assigned as a higher-level grouping of cell types (optional). Useful for organizing cell types into broader categories such as lineage or functional classes.'};
            app.CelltypeLevel2CheckBox.Text = 'Level 2';
            app.SamplesLevel1CheckBox = uicheckbox(app.ClusToRaAnalysisTab);
            app.SamplesLevel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @SamplesLevel1CheckBoxValueChanged, true);
            app.SamplesLevel1CheckBox.Tooltip = {'checkbox indicating that the selected column should be assigned as the sample group or condition. Omit this if analyzing only one sample. Required if the metadata contains multiple samples.'};
            app.SamplesLevel1CheckBox.Text = 'Samples (Level 1)';
            app.ColumnsListBoxLabel = uilabel(app.ClusToRaAnalysisTab);
            app.ColumnsListBoxLabel.Text = 'Columns';
            app.ColumnsListBox = uilistbox(app.ClusToRaAnalysisTab);
            app.ColumnsListBox.Items = {''};
            app.ColumnsListBox.Tooltip = {'text area that populates with detected column names after the metadata file is successfully loaded.'};
            app.ColumnsListBox.Value = '';
            app.Xcoordinput = uitextarea(app.ClusToRaAnalysisTab);
            app.Xcoordinput.Editable = 'off';
            app.Ycoordinput = uitextarea(app.ClusToRaAnalysisTab);
            app.Ycoordinput.Editable = 'off';
            app.CelltypeLevel1input = uitextarea(app.ClusToRaAnalysisTab);
            app.CelltypeLevel1input.Editable = 'off';
            app.CelltypeLevel2input = uitextarea(app.ClusToRaAnalysisTab);
            app.CelltypeLevel2input.Editable = 'off';
            app.SamplesLevel2CheckBox = uicheckbox(app.ClusToRaAnalysisTab);
            app.SamplesLevel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @SamplesLevel2CheckBoxValueChanged, true);
            app.SamplesLevel2CheckBox.Tooltip = {'checkbox indicating that the selected column should be assigned as the individual sample identifiers corresponding to the groups defined in Samples (Level 1)'};
            app.SamplesLevel2CheckBox.Text = 'Level 2';
            app.SamplesLevel1input = uitextarea(app.ClusToRaAnalysisTab);
            app.SamplesLevel1input.Editable = 'off';
            app.SamplesLevel2input = uitextarea(app.ClusToRaAnalysisTab);
            app.SamplesLevel2input.Editable = 'off';
            app.optionalLabel = uilabel(app.ClusToRaAnalysisTab);
            app.optionalLabel.Text = '(optional)';
            app.requiredLabel = uilabel(app.ClusToRaAnalysisTab);
            app.requiredLabel.Text = '(required)';
            app.optionalLabel_2 = uilabel(app.ClusToRaAnalysisTab);
            app.optionalLabel_2.Text = '(optional)';
            app.requiredLabel_2 = uilabel(app.ClusToRaAnalysisTab);
            app.requiredLabel_2.Text = '(required)';
            app.optionalLabel_4 = uilabel(app.ClusToRaAnalysisTab);
            app.optionalLabel_4.Text = '(optional)';
            app.requiredLabel_3 = uilabel(app.ClusToRaAnalysisTab);
            app.requiredLabel_3.Text = '(required)';
            app.StartClusToRaButton = uibutton(app.ClusToRaAnalysisTab, 'push');
            app.StartClusToRaButton.ButtonPushedFcn = createCallbackFcn(app, @StartClusToRaButtonPushed, true);
            app.StartClusToRaButton.Enable = 'off';
            app.StartClusToRaButton.Tooltip = {'button used to start the analysis. The progress bar in the ClusToRa Analysis tab displays the percentage completion of the current analysis.'};
            app.StartClusToRaButton.Text = {'Start'; 'ClusToRa'};
            app.ParametersPanel = uipanel(app.ClusToRaAnalysisTab);
            app.ParametersPanel.Title = 'Parameters';
            app.mincellsSpinnerLabel = uilabel(app.ParametersPanel);
            app.mincellsSpinnerLabel.Text = 'min cells';
            app.mincellsSpinner = uispinner(app.ParametersPanel);
            app.mincellsSpinner.Limits = [3 100];
            app.mincellsSpinner.Tooltip = {'numeric input defining the minimum number of cells required to form a DBSCAN cluster. Default: 4'};
            app.mincellsSpinner.Value = 4;
            app.randomSpinnerLabel = uilabel(app.ParametersPanel);
            app.randomSpinnerLabel.Text = 'random';
            app.randomSpinner = uispinner(app.ParametersPanel);
            app.randomSpinner.Limits = [100 Inf];
            app.randomSpinner.ValueChangedFcn = createCallbackFcn(app, @randomSpinnerValueChanged, true);
            app.randomSpinner.Tooltip = {'numeric input defining the number of iterations per cell type used for null-model generation. Default: 1000'};
            app.randomSpinner.Value = 1000;
            app.epsilonDropDownLabel = uilabel(app.ParametersPanel);
            app.epsilonDropDownLabel.Text = 'epsilon';
            app.epsilonDropDown = uidropdown(app.ParametersPanel);
            app.epsilonDropDown.Items = {'Auto', 'User'};
            app.epsilonDropDown.ValueChangedFcn = createCallbackFcn(app, @epsilonDropDownValueChanged, true);
            app.epsilonDropDown.Tooltip = {'dropdown menu used to define the DBSCAN epsilon parameter. Default: auto, as described in the ClusToRa manuscript.'};
            app.epsilonDropDown.Value = 'Auto';
            app.selfCheckBox = uicheckbox(app.ParametersPanel);
            app.selfCheckBox.Tooltip = {'checkbox indicating whether ClusToRa analysis should include interactions of cell types with themselves.'};
            app.selfCheckBox.Text = 'self';
            app.selfCheckBox.Value = true;
            app.ThreadsSpinnerLabel = uilabel(app.ParametersPanel);
            app.ThreadsSpinnerLabel.Text = 'Threads';
            app.ThreadsSpinner = uispinner(app.ParametersPanel);
            app.ThreadsSpinner.Limits = [1 Inf];
            app.ThreadsSpinner.Tooltip = {'numeric input defining the number of threads to use for parallel computation. Default: maximum available threads'};
            app.ThreadsSpinner.Value = 1;
            app.epsilonUser = uieditfield(app.ParametersPanel, 'numeric');
            app.epsilonUser.AllowEmpty = 'on';
            app.epsilonUser.Value = [];
            app.clumpinessCheckBox = uicheckbox(app.ParametersPanel);
            app.clumpinessCheckBox.Tooltip = {'checkbox indicating whether to compute the Clark–Evans index for cluster centroids.'};
            app.clumpinessCheckBox.Text = 'clumpiness';
            app.clumpinessCheckBox.Value = true;
            app.SaveButton = uibutton(app.ClusToRaAnalysisTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'button used to manually save results when auto save is unchecked. Zscores and infiltration/envelopment ratio table will also be generated.'};
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.ClusToRaAnalysisTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Tooltip = {'button that removes the assigned column from the checked field(s)'};
            app.ClearButton.Text = 'Clear <';
            app.progressGaugeLabel = uilabel(app.ClusToRaAnalysisTab);
            app.progressGaugeLabel.Text = '% progress';
            app.progressGauge = uigauge(app.ClusToRaAnalysisTab, 'linear');
            app.progressGauge.Tooltip = {'Percent ClusToRa analysis progress '};
            app.LoadButton = uibutton(app.ClusToRaAnalysisTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'button used to load previously saved ClusToRa analysis runs.'};
            app.LoadButton.Text = 'Load';
            app.autosaveCheckBox = uicheckbox(app.ClusToRaAnalysisTab);
            app.autosaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @autosaveCheckBoxValueChanged, true);
            app.autosaveCheckBox.Tooltip = {'checkbox indicating that ClusToRa results will be automatically saved. When selected, you will be prompted to choose a folder in which results will be saved as a MATLAB .mat file. Zscores and infiltration/envelopment ratio table will also be generated'};
            app.autosaveCheckBox.Enable = 'off';
            app.autosaveCheckBox.Text = 'auto save';
            app.Lamp = uilamp(app.ClusToRaAnalysisTab);
            app.Lamp.Tooltip = {'Red when analysis is in progress, otherwise gray'};
            app.CelltypeLevel2Options = uidropdown(app.ClusToRaAnalysisTab);
            app.CelltypeLevel2Options.Items = {'Options', 'export types', 'import types'};
            app.CelltypeLevel2Options.ValueChangedFcn = createCallbackFcn(app, @CelltypeLevel2OptionsValueChanged, true);
            app.CelltypeLevel2Options.Enable = 'off';
            app.CelltypeLevel2Options.Value = 'Options';
            app.delimiterDropDownLabel = uilabel(app.ClusToRaAnalysisTab);
            app.delimiterDropDownLabel.Text = 'delimiter';
            app.delimiterDropDown = uidropdown(app.ClusToRaAnalysisTab);
            app.delimiterDropDown.Items = {'Auto', ',', '\t', ' ', ';', '|', 'User'};
            app.delimiterDropDown.ValueChangedFcn = createCallbackFcn(app, @delimiterDropDownValueChanged, true);
            app.delimiterDropDown.Tooltip = {'dropdown menu allowing manual specification of the metadata delimiter. Default: auto'};
            app.delimiterDropDown.Value = 'Auto';
            app.assignbarcodesButton = uibutton(app.ClusToRaAnalysisTab, 'push');
            app.assignbarcodesButton.ButtonPushedFcn = createCallbackFcn(app, @assignbarcodesButtonPushed, true);
            app.assignbarcodesButton.Tooltip = {'button used to assign the selected column as the barcode/cell ID field for the dataset (required if computing downstream differential analysis).'};
            app.assignbarcodesButton.Text = 'assign barcodes';
            app.barcodescolumn = uitextarea(app.ClusToRaAnalysisTab);
            app.barcodescolumn.Editable = 'off';
            app.optionalLabel_5 = uilabel(app.ClusToRaAnalysisTab);
            app.optionalLabel_5.Text = '(optional)';
            app.advancedDropDownLabel = uilabel(app.ClusToRaAnalysisTab);
            app.advancedDropDownLabel.Text = 'advanced';
            app.advancedDropDown = uidropdown(app.ClusToRaAnalysisTab);
            app.advancedDropDown.Items = {'', 'Long barcodes'};
            app.advancedDropDown.Tooltip = {'dropdown menu containing the Long barcodes option. Enable this if your metadata contains long numeric barcodes (common in some Vizgen MERFISH datasets).'};
            app.advancedDropDown.Value = '';
            app.metaCheckBox = uicheckbox(app.ClusToRaAnalysisTab);
            app.metaCheckBox.ValueChangedFcn = createCallbackFcn(app, @metaCheckBoxValueChanged, true);
            app.metaCheckBox.Tooltip = {'checkbox indicating that the preview will display the loaded metadata table.'};
            app.metaCheckBox.Text = 'meta';
            app.metaCheckBox.Value = true;
            app.xygCheckBox = uicheckbox(app.ClusToRaAnalysisTab);
            app.xygCheckBox.ValueChangedFcn = createCallbackFcn(app, @xygCheckBoxValueChanged, true);
            app.xygCheckBox.Tooltip = {'checkbox indicating that the preview will display the spatial X/Y distribution of cells, with cell types pseudo-colored by annotation. '};
            app.xygCheckBox.Text = 'xyg';
            app.ClusToRaPlottingTab = uitab(app.TabGroup);
            app.ClusToRaPlottingTab.Title = 'ClusToRa Plotting';
            app.Level2ListBoxLabel = uilabel(app.ClusToRaPlottingTab);
            app.Level2ListBoxLabel.Text = 'Level2';
            app.Level2ListBox = uilistbox(app.ClusToRaPlottingTab);
            app.Level2ListBox.Items = {''};
            app.Level2ListBox.Multiselect = 'on';
            app.Level2ListBox.ValueChangedFcn = createCallbackFcn(app, @Level2ListBoxValueChanged, true);
            app.Level2ListBox.Enable = 'off';
            app.Level2ListBox.Tooltip = {'text area populated using assignments from Cell type (Level 2) in the ClusToRa Analysis tab. Use this to select samples for plotting when a second grouping level was defined.'};
            app.Level2ListBox.Value = {''};
            app.importButton = uibutton(app.ClusToRaPlottingTab, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Tooltip = {'button used to load your MATLAB .mat result file. If switch is set to loaded, the app imports the currently loaded or recently completed ClusToRa Analysis result. If switch is set to drive, a file-selection prompt will appear allowing you to choose a saved .mat file.'};
            app.importButton.Text = 'import';
            app.SwitchLoad = uiswitch(app.ClusToRaPlottingTab, 'slider');
            app.SwitchLoad.Items = {'loaded', 'drive'};
            app.SwitchLoad.Tooltip = {'switch specifying whether to import results from the currently loaded session or from a saved file on disk.'};
            app.SwitchLoad.Value = 'drive';
            app.Level1ListBoxLabel = uilabel(app.ClusToRaPlottingTab);
            app.Level1ListBoxLabel.Text = 'Level1';
            app.Level1ListBox = uilistbox(app.ClusToRaPlottingTab);
            app.Level1ListBox.Items = {''};
            app.Level1ListBox.ValueChangedFcn = createCallbackFcn(app, @Level1ListBoxValueChanged, true);
            app.Level1ListBox.Tooltip = {'text area populated using the assignments from Cell type (Level 1) in the ClusToRa Analysis tab. Use this to select groups or samples for plotting.'};
            app.Level1ListBox.Value = '';
            app.ChordplotparametersPanel = uipanel(app.ClusToRaPlottingTab);
            app.ChordplotparametersPanel.Enable = 'off';
            app.ChordplotparametersPanel.Title = 'Chord plot (parameters)';
            app.ColorMap = uiaxes(app.ChordplotparametersPanel);
            app.ColorMap.XTick = [];
            app.ColorMap.XTickLabel = {};
            app.ColorMap.YTick = [];
            app.scoreLabel = uilabel(app.ChordplotparametersPanel);
            app.scoreLabel.Text = 'score';
            app.scoreDropDown = uidropdown(app.ChordplotparametersPanel);
            app.scoreDropDown.Items = {'Z-scores', 'In/Out (log2FC)'};
            app.scoreDropDown.ValueChangedFcn = createCallbackFcn(app, @scoreDropDownValueChanged, true);
            app.scoreDropDown.Tooltip = {'dropdown menu used to define the interaction metric to plot: ClusToRa Z-scores or In/Out (log2FC) (log2 of Infiltration/Envelopment ratio), as described in the ClusToRa manuscript.'};
            app.scoreDropDown.Value = 'Z-scores';
            app.ThreshSpinnerLabel = uilabel(app.ChordplotparametersPanel);
            app.ThreshSpinnerLabel.Text = 'Thresh';
            app.ThreshSpinner = uispinner(app.ChordplotparametersPanel);
            app.ThreshSpinner.Limits = [1.96 Inf];
            app.ThreshSpinner.Tooltip = {'numeric input defining the positive/negative threshold value used for plotting interactions based on the selected score option. Default: 1.96 for Z-scores; 0 for In/Out (log2FC) since this ratio is computed only for interactions with positive 1.96 z-scores.'};
            app.ThreshSpinner.Value = 1.96;
            app.ColormapLabel = uilabel(app.ChordplotparametersPanel);
            app.ColormapLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.ChordplotparametersPanel);
            app.ColormapDropDown.Items = {'ClusToRa_colormap', 'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'sky', 'abyss', 'nebula', 'jet', 'lines', 'colorcube', 'prism', 'flag'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Tooltip = {'dropdown menu used to define the colormap for plotting. Default: custom one called ClusToRa_colormap. An adjacent dropdown menu defines whether the color scale spans all available z-scores within a group (Auto), maximum in whole dataset (Global), maximum score within the interactions to plot(Local), or User defined numeric value.'};
            app.ColormapDropDown.Value = 'ClusToRa_colormap';
            app.ToplotpreviewLabel = uilabel(app.ChordplotparametersPanel);
            app.ToplotpreviewLabel.Text = 'To plot / preview';
            app.ToplotpreviewDropDown = uidropdown(app.ChordplotparametersPanel);
            app.ToplotpreviewDropDown.Items = {'Level1'};
            app.ToplotpreviewDropDown.ValueChangedFcn = createCallbackFcn(app, @ToplotpreviewDropDownValueChanged, true);
            app.ToplotpreviewDropDown.Tooltip = {'dropdown menu used to define whether plots are generated from Level1 or Level2 assignments (setup dependent). '};
            app.ToplotpreviewDropDown.Value = 'Level1';
            app.alphaSpinnerLabel = uilabel(app.ChordplotparametersPanel);
            app.alphaSpinnerLabel.Text = 'alpha';
            app.alphaSpinner = uispinner(app.ChordplotparametersPanel);
            app.alphaSpinner.Step = 0.05;
            app.alphaSpinner.Limits = [0.05 1];
            app.alphaSpinner.Tooltip = {'numeric input defining transparency of chord arrows. Adjusting transparency improves visualization when multiple interaction arrows overlap..'};
            app.alphaSpinner.Value = 0.8;
            app.colorsDropDownLabel = uilabel(app.ChordplotparametersPanel);
            app.colorsDropDownLabel.Text = 'colors';
            app.colorsDropDown = uidropdown(app.ChordplotparametersPanel);
            app.colorsDropDown.Items = {'Auto', 'export', 'import'};
            app.colorsDropDown.ValueChangedFcn = createCallbackFcn(app, @colorsDropDownValueChanged, true);
            app.colorsDropDown.Enable = 'off';
            app.colorsDropDown.Tooltip = {'dropdown menu defining whether cell-type colors are automatically assigned. Selecting export outputs a text file containing cell types (column 1) and their corresponding row-wise RGB color values (columns 2–4; values range from 0–1). You may edit these RGB values to create custom color schemes and reload them using the import option. Default: auto.'};
            app.colorsDropDown.Value = 'Auto';
            app.PlotChordButton = uibutton(app.ChordplotparametersPanel, 'push');
            app.PlotChordButton.ButtonPushedFcn = createCallbackFcn(app, @PlotChordButtonPushed, true);
            app.PlotChordButton.Enable = 'off';
            app.PlotChordButton.Tooltip = {'button generates the chord plot using the selected parameters.'};
            app.PlotChordButton.Text = 'Chord';
            app.minsampleLabel = uilabel(app.ChordplotparametersPanel);
            app.minsampleLabel.Text = '%min sample';
            app.minsampleSpinner = uispinner(app.ChordplotparametersPanel);
            app.minsampleSpinner.Limits = [5 100];
            app.minsampleSpinner.Enable = 'off';
            app.minsampleSpinner.Tooltip = {'numeric input defining the minimum percentage of samples within the selected group that must contain a given interaction for it to be plotted.'};
            app.minsampleSpinner.Value = 50;
            app.cMaxChord = uidropdown(app.ChordplotparametersPanel);
            app.cMaxChord.Items = {'Group', 'Local', 'Global', 'User'};
            app.cMaxChord.ValueChangedFcn = createCallbackFcn(app, @cMaxChordValueChanged, true);
            app.cMaxChord.Value = 'Local';
            app.ScatterparametersPanel = uipanel(app.ClusToRaPlottingTab);
            app.ScatterparametersPanel.Enable = 'off';
            app.ScatterparametersPanel.Title = 'Scatter (parameters)';
            app.ScatterOptions = uidropdown(app.ScatterparametersPanel);
            app.ScatterOptions.Items = {'Options', 'All selected', 'Cluster(s)', 'Cluster & non-Cluster', 'Co-localizing'};
            app.ScatterOptions.ValueChangedFcn = createCallbackFcn(app, @ScatterOptionsValueChanged, true);
            app.ScatterOptions.Enable = 'off';
            app.ScatterOptions.Tooltip = {'dropdown menu containing 4 options corresponding to the checkboxes below. These options assign cell types selected from the tree node'};
            app.ScatterOptions.Value = 'Options';
            app.AllselectedCheckBox = uicheckbox(app.ScatterparametersPanel);
            app.AllselectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllselectedCheckBoxValueChanged, true);
            app.AllselectedCheckBox.Tooltip = {'checkbox when checked, plots all cells assigned above.'};
            app.AllselectedCheckBox.Text = 'All  selected';
            app.ClusternonClusterCheckBox = uicheckbox(app.ScatterparametersPanel);
            app.ClusternonClusterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClusternonClusterCheckBoxValueChanged, true);
            app.ClusternonClusterCheckBox.Tooltip = {'checkbox when checked, plots clustered cells for the selected cell type together with non-clustered cells displayed using a lighter shade controlled by alpha'};
            app.ClusternonClusterCheckBox.Text = 'Cluster & non-Cluster';
            app.ClustersCheckBox = uicheckbox(app.ScatterparametersPanel);
            app.ClustersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClustersCheckBoxValueChanged, true);
            app.ClustersCheckBox.Tooltip = {'checkbox when checked, plots only clusters corresponding to the assigned cell types.'};
            app.ClustersCheckBox.Text = 'Cluster(s)';
            app.ColocalizingCheckBox = uicheckbox(app.ScatterparametersPanel);
            app.ColocalizingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColocalizingCheckBoxValueChanged, true);
            app.ColocalizingCheckBox.Tooltip = {'checkbox when checked, plots cells assigned above that spatially co-localize with the cluster(s) defined under Cluster.'; '-Size numeric input defining scatter marker size'};
            app.ColocalizingCheckBox.Text = 'Co-localizing';
            app.Label = uilabel(app.ScatterparametersPanel);
            app.Label.Text = 'Size●●';
            app.MarkerSizeSpinner = uispinner(app.ScatterparametersPanel);
            app.MarkerSizeSpinner.Step = 0.05;
            app.MarkerSizeSpinner.Limits = [0.05 100];
            app.MarkerSizeSpinner.Tooltip = {'numeric input defining scatter marker size.'};
            app.MarkerSizeSpinner.Value = 3;
            app.PlotScatterButton = uibutton(app.ScatterparametersPanel, 'push');
            app.PlotScatterButton.ButtonPushedFcn = createCallbackFcn(app, @PlotScatterButtonPushed, true);
            app.PlotScatterButton.Enable = 'off';
            app.PlotScatterButton.Tooltip = {'button generates scatter plots using the selected parameters'};
            app.PlotScatterButton.Text = 'plot';
            app.Label_3 = uilabel(app.ScatterparametersPanel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '●●';
            app.Label_1 = uilabel(app.ScatterparametersPanel);
            app.Label_1.Visible = 'off';
            app.Label_1.Text = '●●';
            app.Label_2 = uilabel(app.ScatterparametersPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '●●';
            app.Label_4 = uilabel(app.ScatterparametersPanel);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '●●';
            app.alphaSpinner_2Label = uilabel(app.ScatterparametersPanel);
            app.alphaSpinner_2Label.Text = 'alpha';
            app.alphaSpinner_2 = uispinner(app.ScatterparametersPanel);
            app.alphaSpinner_2.Step = 0.05;
            app.alphaSpinner_2.Limits = [0.05 1];
            app.alphaSpinner_2.Value = 0.5;
            app.Tree = uitree(app.ClusToRaPlottingTab, 'checkbox');
            app.Tree.Tooltip = {'tree node used to select cell types for scatter plotting'};
            app.SelectCellsToScatterPlotNode = uitreenode(app.Tree);
            app.SelectCellsToScatterPlotNode.Text = 'Select Cells To Scatter Plot';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.CellTypePreviewButton = uibutton(app.ClusToRaPlottingTab, 'push');
            app.CellTypePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @CellTypePreviewButtonPushed, true);
            app.CellTypePreviewButton.Enable = 'off';
            app.CellTypePreviewButton.Tooltip = {'button providing a quick preview of detected cell types and higher-level groupings (if defined).'};
            app.CellTypePreviewButton.Text = 'CellTypePreview';
            app.SaveFigures = uipanel(app.ClusToRaPlottingTab);
            app.filetypetosave = uidropdown(app.ClusToRaPlottingTab);
            app.filetypetosave.Items = {'pdf', 'svg', 'png', 'tif', 'jpg', 'emf', 'eps'};
            app.filetypetosave.Tooltip = {'dropdown menu defining export format (.pdf, .svg, .png, .tif, .jpg, .emf, .eps). Note: .pdf, .svg,.emf and .eps format are exported as vector graphics'};
            app.filetypetosave.Value = 'pdf';
            app.filetypeLabel = uilabel(app.ClusToRaPlottingTab);
            app.filetypeLabel.Text = 'file type';
            app.DPItosave = uidropdown(app.ClusToRaPlottingTab);
            app.DPItosave.Items = {'150', '300', '600', '900', '1200'};
            app.DPItosave.Tooltip = {'dropdown menu defining export resolution.'};
            app.DPItosave.Value = '300';
            app.DPILabel = uilabel(app.ClusToRaPlottingTab);
            app.DPILabel.Text = 'DPI';
            app.SavePlotsButton = uibutton(app.ClusToRaPlottingTab, 'push');
            app.SavePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotsButtonPushed, true);
            app.SavePlotsButton.Tooltip = {'button used to save all open plots to a user-selected folder'};
            app.SavePlotsButton.Text = 'Save Plots';
            app.ClusterSummary = uipanel(app.ClusToRaPlottingTab);
            app.ClusterSummary.Enable = 'off';
            app.ClustersbarssummaryButton = uibutton(app.ClusterSummary, 'push');
            app.ClustersbarssummaryButton.ButtonPushedFcn = createCallbackFcn(app, @ClustersbarssummaryButtonPushed, true);
            app.ClustersbarssummaryButton.Enable = 'off';
            app.ClustersbarssummaryButton.Tooltip = {'button generates 1–3 plots (depending on setup) together with their associated tables, which can be exported as .xlsx or .txt files.'};
            app.ClustersbarssummaryButton.Text = 'Clusters & bars summary';
            app.filloptionLabel = uilabel(app.ClusterSummary);
            app.filloptionLabel.Text = 'fill option';
            app.CircleFill = uidropdown(app.ClusterSummary);
            app.CircleFill.Items = {'%', 'CEI'};
            app.CircleFill.ValueChangedFcn = createCallbackFcn(app, @CircleFillValueChanged, true);
            app.CircleFill.Tooltip = {'dropdown menu used to define the metric used to color the bubbles'};
            app.CircleFill.Value = 'CEI';
            app.filloptionLabel_2 = uilabel(app.ClusterSummary);
            app.filloptionLabel_2.Text = 'O size';
            app.CircleSize = uidropdown(app.ClusterSummary);
            app.CircleSize.Items = {'same', 'range'};
            app.CircleSize.ValueChangedFcn = createCallbackFcn(app, @CircleSizeValueChanged, true);
            app.CircleSize.Tooltip = {'dropdown menu used to define whether bubble sizes remain constant or vary according to a selected metric.'};
            app.CircleSize.Value = 'range';
            app.ColocMatrixButton = uibutton(app.ClusToRaPlottingTab, 'push');
            app.ColocMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ColocMatrixButtonPushed, true);
            app.ColocMatrixButton.Enable = 'off';
            app.ColocMatrixButton.Tooltip = {'button used to export the colocalization matrix for significant interactions (defined by Thresh numeric input) from the analysis. The first three columns contain the arcodes (if assigned prior to analysis), X coordinates, and Y coordinates. The remaining columns correspond to the ColocMatrix. Binary values in CellA[Clusters] indicate membership of CellA in clusters. Entries in CellA[Clusters],CellB[Coloc+1, nonColoc-1] indicate whether members of CellB colocalize (+1) or do not colocalize (-1) with CellA clusters. Supported export formats include .txt, .mat, and .mtx. The adjacent dropdown menu selects the export format.'};
            app.ColocMatrixButton.Text = 'ColocMatrix';
            app.ColocMatrixFileType = uidropdown(app.ClusToRaPlottingTab);
            app.ColocMatrixFileType.Items = {'matlab', 'txt', 'mtx'};
            app.ColocMatrixFileType.Value = 'txt';
            app.ClusToRaDifferentialTab = uitab(app.TabGroup);
            app.ClusToRaDifferentialTab.Title = 'ClusToRa Differential';
            app.DiffSetupPanel = uipanel(app.ClusToRaDifferentialTab);
            app.DiffSetupPanel.Enable = 'off';
            app.LoadexpressionLabel = uilabel(app.DiffSetupPanel);
            app.LoadexpressionLabel.Text = 'Load expression';
            app.InteractionforEnrichranalysisListBoxLabel = uilabel(app.DiffSetupPanel);
            app.InteractionforEnrichranalysisListBoxLabel.Text = 'Interaction for Enrichr analysis';
            app.InteractionforGSEAanalysisListBox = uilistbox(app.DiffSetupPanel);
            app.InteractionforGSEAanalysisListBox.Items = {''};
            app.InteractionforGSEAanalysisListBox.Tooltip = {'text area (Interaction for Enrichr analysis) displaying interactions identified within the selected sample or group.'};
            app.InteractionforGSEAanalysisListBox.Value = '';
            app.ALLinOneButton = uibutton(app.DiffSetupPanel, 'push');
            app.ALLinOneButton.ButtonPushedFcn = createCallbackFcn(app, @ALLinOneButtonPushed, true);
            app.ALLinOneButton.Tooltip = {'button used to load a complete expression dataset where cells are rows and features (e.g., genes) are columns. Barcodes should be located in column 1, and column headers (row 1) should contain feature/gene names. Loaded barcodes must be present in the metadata barcodes used during ClusToRa Analysis. Supported formats: .txt, .csv, .tsv, .xlsx, and .xls.'};
            app.ALLinOneButton.Text = 'ALL in One';
            app.Option1Label = uilabel(app.DiffSetupPanel);
            app.Option1Label.Text = 'Option 1';
            app.MTXfileButton = uibutton(app.DiffSetupPanel, 'push');
            app.MTXfileButton.ButtonPushedFcn = createCallbackFcn(app, @MTXfileButtonPushed, true);
            app.MTXfileButton.Enable = 'off';
            app.MTXfileButton.Tooltip = {'button used to load the expression matrix (.mtx format; sparse or dense supported). Matrix row count must match barcode count, and column count must match feature/gene count.'};
            app.MTXfileButton.Text = 'MTX file';
            app.featuresButton = uibutton(app.DiffSetupPanel, 'push');
            app.featuresButton.ButtonPushedFcn = createCallbackFcn(app, @featuresButtonPushed, true);
            app.featuresButton.Enable = 'off';
            app.featuresButton.Tooltip = {'button used to load feature/gene names separately. If multiple columns exist, only column 1 will be used. Successful loading activates the MTX file button. Supported formats: .txt, .csv, .tsv, .xlsx, and .xls.'};
            app.featuresButton.Text = 'features';
            app.barcodesButton = uibutton(app.DiffSetupPanel, 'push');
            app.barcodesButton.ButtonPushedFcn = createCallbackFcn(app, @barcodesButtonPushed, true);
            app.barcodesButton.Tooltip = {'button used to load expression dataset barcodes separately. If multiple columns exist, only column 1 will be used. Successful loading activates the features button. Supported formats: .txt, .csv, .tsv, .xlsx, and .xls.'};
            app.barcodesButton.Text = 'barcodes';
            app.Option2Label = uilabel(app.DiffSetupPanel);
            app.Option2Label.Text = 'Option 2';
            app.PreviewloadButton = uibutton(app.DiffSetupPanel, 'push');
            app.PreviewloadButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewloadButtonPushed, true);
            app.PreviewloadButton.Enable = 'off';
            app.PreviewloadButton.Tooltip = {'button opens a preview table displaying up to 100 rows and 100 columns of the loaded barcodes, features/genes, and expression matrix.'};
            app.PreviewloadButton.Text = 'Preview load';
            app.normalizeCheckBox = uicheckbox(app.DiffSetupPanel);
            app.normalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @normalizeCheckBoxValueChanged, true);
            app.normalizeCheckBox.Tooltip = {'checkbox specifying whether expression data should be normalized. When enabled, each cell is normalized to a total count equal to the median total count across all cells prior to normalization.'};
            app.normalizeCheckBox.Enable = 'off';
            app.normalizeCheckBox.Text = 'normalize';
            app.PreviewExpButton = uibutton(app.DiffSetupPanel, 'push');
            app.PreviewExpButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewExpButtonPushed, true);
            app.PreviewExpButton.Enable = 'off';
            app.PreviewExpButton.Tooltip = {'button randomly selects 50 cells and generates boxplots of feature/gene expression distributions. Total counts per cell are displayed on the x-axis, which is useful for assessing whether the data may already be normalized.'};
            app.PreviewExpButton.Text = 'Preview Exp';
            app.searchTextArea = uitextarea(app.DiffSetupPanel);
            app.searchTextArea.ValueChangingFcn = createCallbackFcn(app, @searchTextAreaValueChanging, true);
            app.searchTextArea.Tooltip = {'text area allowing rapid searching for interactions of interest within the main interaction list.'};
            app.Tree_2 = uitree(app.DiffSetupPanel, 'checkbox');
            app.Tree_2.Tooltip = {'tree node used to select Enrichr database(s) for enrichment analysis of the selected interaction'};
            app.SelectGeneSetdatabaseNode = uitreenode(app.Tree_2);
            app.SelectGeneSetdatabaseNode.Text = 'Select GeneSet database';
            app.Reactome_Pathways_2024Node = uitreenode(app.SelectGeneSetdatabaseNode);
            app.Reactome_Pathways_2024Node.Text = 'Reactome_Pathways_2024';
            app.KEGG_2026Node = uitreenode(app.SelectGeneSetdatabaseNode);
            app.KEGG_2026Node.Text = 'KEGG_2026';
            app.Elsevier_Pathway_CollectionNode = uitreenode(app.SelectGeneSetdatabaseNode);
            app.Elsevier_Pathway_CollectionNode.Text = 'Elsevier_Pathway_Collection';
            app.MSigDB_Hallmark_2020Node = uitreenode(app.SelectGeneSetdatabaseNode);
            app.MSigDB_Hallmark_2020Node.Text = 'MSigDB_Hallmark_2020';
            app.MSigDB_Oncogenic_SignaturesNode = uitreenode(app.SelectGeneSetdatabaseNode);
            app.MSigDB_Oncogenic_SignaturesNode.Text = 'MSigDB_Oncogenic_Signatures';
            app.WikiPathways_2024_HumanNode = uitreenode(app.SelectGeneSetdatabaseNode);
            app.WikiPathways_2024_HumanNode.Text = 'WikiPathways_2024_Human';
            app.WikiPathways_2024_MouseNode = uitreenode(app.SelectGeneSetdatabaseNode);
            app.WikiPathways_2024_MouseNode.Text = 'WikiPathways_2024_Mouse';
            app.GO_Biological_Process_2025Node = uitreenode(app.SelectGeneSetdatabaseNode);
            app.GO_Biological_Process_2025Node.Text = 'GO_Biological_Process_2025';
            app.GO_Molecular_Function_2025Node = uitreenode(app.SelectGeneSetdatabaseNode);
            app.GO_Molecular_Function_2025Node.Text = 'GO_Molecular_Function_2025';
            app.GO_Cellular_Component_2025Node = uitreenode(app.SelectGeneSetdatabaseNode);
            app.GO_Cellular_Component_2025Node.Text = 'GO_Cellular_Component_2025';
            app.BioPlanet_2019Node = uitreenode(app.SelectGeneSetdatabaseNode);
            app.BioPlanet_2019Node.Text = 'BioPlanet_2019';
            app.BioCarta_2016Node = uitreenode(app.SelectGeneSetdatabaseNode);
            app.BioCarta_2016Node.Text = 'BioCarta_2016';
            app.Panther_2016Node = uitreenode(app.SelectGeneSetdatabaseNode);
            app.Panther_2016Node.Text = 'Panther_2016';
            app.usePlottingtabtoselectsamplesLabel = uilabel(app.DiffSetupPanel);
            app.usePlottingtabtoselectsamplesLabel.Text = '** use Plotting tab to select sample(s)';
            app.DiffPanel = uipanel(app.DiffSetupPanel);
            app.ZthreshSpinnerLabel = uilabel(app.DiffPanel);
            app.ZthreshSpinnerLabel.Text = 'Z -thresh';
            app.ZthreshSpinner = uispinner(app.DiffPanel);
            app.ZthreshSpinner.Limits = [1.96 Inf];
            app.ZthreshSpinner.Tooltip = {' numeric input defining the positive Z-score threshold used to determine which interactions will undergo differential analysis. Default: 1.96.'};
            app.ZthreshSpinner.Value = 1.96;
            app.RunDifferentialButton = uibutton(app.DiffPanel, 'push');
            app.RunDifferentialButton.ButtonPushedFcn = createCallbackFcn(app, @RunDifferentialButtonPushed, true);
            app.RunDifferentialButton.Tooltip = {'button performs differential gene expression analysis on all interactions passing the selected Z-thresh across all samples.'};
            app.RunDifferentialButton.Text = {'Run'; 'Diff'};
            app.PreviewDiffButton = uibutton(app.DiffPanel, 'push');
            app.PreviewDiffButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewDiffButtonPushed, true);
            app.PreviewDiffButton.Enable = 'off';
            app.PreviewDiffButton.Tooltip = {'(enabled using adjacent ☐ checkbox) button generates a table of differential analysis results for the currently selected sample or group from the ClusToRa Plotting tab.'};
            app.PreviewDiffButton.Text = 'Preview Diff';
            app.ExportButton = uibutton(app.DiffPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Tooltip = {'button saves all differential analysis results for individual samples. If grouped samples exist, combined group-level results are also exported. The adjacent dropdown menu defines the export format.'};
            app.ExportButton.Text = 'Export';
            app.typeDropDown = uidropdown(app.DiffPanel);
            app.typeDropDown.Items = {'txt', 'xlsx'};
            app.typeDropDown.Value = 'txt';
            app.ShowDiffPreview = uicheckbox(app.DiffPanel);
            app.ShowDiffPreview.ValueChangedFcn = createCallbackFcn(app, @ShowDiffPreviewValueChanged, true);
            app.ShowDiffPreview.Text = '';
            app.ShowDiffPreview.Value = true;
            app.searchinteractionLabel = uilabel(app.DiffSetupPanel);
            app.searchinteractionLabel.Text = '*search interaction';
            app.GSEApanel = uipanel(app.DiffSetupPanel);
            app.GeneAnalysisButton = uibutton(app.GSEApanel, 'push');
            app.GeneAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @GeneAnalysisButtonPushed, true);
            app.GeneAnalysisButton.Enable = 'off';
            app.GeneAnalysisButton.Tooltip = {'button performs over-representation analysis (ORA) on significantly upregulated and downregulated genes (analyzed separately) comparing cells co-localizing with clusters versus non-co-localizing cells for the selected interaction (Section 3 below). Enrichment analysis is performed using selected Enrichr database libraries from Select GeneSet database. Genes included in the analysis are determined using the thresholds below. Background gene list is all the genes in the loaded expression data'};
            app.GeneAnalysisButton.Text = 'Gene Analysis';
            app.DiffalphaSpinnerLabel = uilabel(app.GSEApanel);
            app.DiffalphaSpinnerLabel.Text = {'Diff'; 'alpha'};
            app.DiffalphaSpinner = uispinner(app.GSEApanel);
            app.DiffalphaSpinner.Limits = [0 1];
            app.DiffalphaSpinner.Tooltip = {'numeric input defining the significance threshold (alpha) used for differential gene analysis based on either p-value or FDR.'};
            app.DiffalphaSpinner.Value = 0.05;
            app.typeDropDown_2Label = uilabel(app.GSEApanel);
            app.typeDropDown_2Label.Text = 'type';
            app.DiffType = uidropdown(app.GSEApanel);
            app.DiffType.Items = {'pVal', 'FDR'};
            app.DiffType.Tooltip = {'dropdown menu specifying whether Diff alpha should be applied to raw p-values or FDR-adjusted values.'};
            app.DiffType.Value = 'pVal';
            app.EnrichralphaSpinnerLabel = uilabel(app.GSEApanel);
            app.EnrichralphaSpinnerLabel.Text = {'Enrichr'; 'alpha'};
            app.EnrichralphaSpinner = uispinner(app.GSEApanel);
            app.EnrichralphaSpinner.Limits = [0 1];
            app.EnrichralphaSpinner.Tooltip = {'numeric input defining the significance threshold (alpha) used for enrichment hits returned by Enrichr.'};
            app.EnrichralphaSpinner.Value = 0.05;
            app.minGenesSpinnerLabel = uilabel(app.GSEApanel);
            app.minGenesSpinnerLabel.Text = {'min'; 'Genes'};
            app.minGenesSpinner = uispinner(app.GSEApanel);
            app.minGenesSpinner.Limits = [1 Inf];
            app.minGenesSpinner.RoundFractionalValues = 'on';
            app.minGenesSpinner.Tooltip = {'numeric input defining the minimum number of overlapping genes required for an enrichment hit to be retained.'};
            app.minGenesSpinner.Value = 3;
            app.AdddatabaseButton = uibutton(app.DiffSetupPanel, 'push');
            app.AdddatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AdddatabaseButtonPushed, true);
            app.AdddatabaseButton.Tooltip = {'button allowing addition of Enrichr databases not currently listed in the tree node.'};
            app.AdddatabaseButton.Text = 'Add database';
            app.Hyperlink = uihyperlink(app.DiffSetupPanel);
            app.Hyperlink.Tooltip = {'click link to view the complete list of Enrichr-supported databases'};
            app.Hyperlink.URL = 'https://maayanlab.cloud/Enrichr/#libraries';
            app.Hyperlink.Text = 'Database libraries';
            app.Diff_selected = uitextarea(app.DiffSetupPanel);
            app.Diff_selected.Tooltip = {'text area displaying the currently selected sample or group. Sample/group selection is controlled from the Level1 and Level2 options in the ClusToRa Plotting tab.'};
            app.pairsTextAreaLabel = uilabel(app.DiffSetupPanel);
            app.pairsTextAreaLabel.Text = 'pairs';
            app.pairsTextArea = uitextarea(app.DiffSetupPanel);
            app.pairsTextArea.Editable = 'off';
            app.pairsTextArea.Tooltip = {' text area displaying the total number of interactions listed in the main text area.'};
            app.DiffrunprogressGaugeLabel = uilabel(app.DiffSetupPanel);
            app.DiffrunprogressGaugeLabel.Text = 'Diff run % progress';
            app.DiffrunprogressGauge = uigauge(app.DiffSetupPanel, 'linear');
            app.DiffrunprogressGauge.Tooltip = {'Percent ClusToRa analysis progress '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClusToRa_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end